classdef MT_Field_App_2022b_mk72 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MT_Field_App_2022b              matlab.ui.Figure
        ProcessFreqList                 matlab.ui.control.Table
        PROCESSING                      matlab.ui.container.TabGroup
        WorkingDir                      matlab.ui.container.Tab
        AddSite_TableButton             matlab.ui.control.Button
        P_LEMIDirEditField_2            matlab.ui.control.EditField
        LemiMTDirLabel                  matlab.ui.control.Label
        SurveyDataUITable               matlab.ui.control.Table
        SurveyDataEditField             matlab.ui.control.EditField
        SurveyDataEditFieldLabel        matlab.ui.control.Label
        SelectSurveyDataButton          matlab.ui.control.Button
        P_WorkingDirEditField           matlab.ui.control.EditField
        WorkingDirEditField_2Label      matlab.ui.control.Label
        P_ChangeWorkingDirButton        matlab.ui.control.Button
        TimeSeriesTab                   matlab.ui.container.Tab
        SelectTimeSeriesLabel           matlab.ui.control.Label
        TimeSeriesTree                  matlab.ui.container.Tree
        Panel_TimeSeries                matlab.ui.container.Panel
        PowerSpectraTab                 matlab.ui.container.Tab
        Panel_PowerSpectra              matlab.ui.container.Panel
        FilterDataTab                   matlab.ui.container.Tab
        Panel_PowerSpectra_Filt         matlab.ui.container.Panel
        Panel_TimeSeries_Filt           matlab.ui.container.Panel
        Panel_5                         matlab.ui.container.Panel
        ButtonDel                       matlab.ui.control.Button
        ButtonAdd                       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        WidthSlider                     matlab.ui.control.Slider
        HarmonicsCheckBox               matlab.ui.control.CheckBox
        HertzCheckBox                   matlab.ui.control.CheckBox
        FilterforDropDown               matlab.ui.control.DropDown
        FilterforDropDown_2Label        matlab.ui.control.Label
        PeriodPulsesEditField           matlab.ui.control.NumericEditField
        PeriodPulsesEditField_2Label    matlab.ui.control.Label
        RemoveOutliersCheckBox          matlab.ui.control.CheckBox
        RemovePeriodocityCheckBox       matlab.ui.control.CheckBox
        FilteredCheckBox                matlab.ui.control.CheckBox
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditField_2Label          matlab.ui.control.Label
        RunFiltersButton                matlab.ui.control.Button
        ProcessDataTab                  matlab.ui.container.Tab
        ResetQueueButton                matlab.ui.control.Button
        Panel_Slider                    matlab.ui.container.Panel
        FinishEditField                 matlab.ui.control.EditField
        FinishEditFieldLabel            matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        FinishSpinner                   matlab.ui.control.Spinner
        FinishSpinnerLabel              matlab.ui.control.Label
        StartSpinner                    matlab.ui.control.Spinner
        StartSpinnerLabel               matlab.ui.control.Label
        MagChannelReplaceList           matlab.ui.control.ListBox
        MagChannelReplaceDropDown       matlab.ui.control.DropDown
        MagChannelReplaceDropDownLabel  matlab.ui.control.Label
        ProcessQueueButton              matlab.ui.control.Button
        addtoQueueButton                matlab.ui.control.Button
        ProcessingOptionButtonGroup     matlab.ui.container.ButtonGroup
        IP_withRR_ProcessButton         matlab.ui.control.ToggleButton
        IP_withSS_ProcessButton         matlab.ui.control.ToggleButton
        MT_withRRSS_ProcessButton       matlab.ui.control.ToggleButton
        MT_withSS_ProcessButton         matlab.ui.control.ToggleButton
        MT_withRR_ProcessButton         matlab.ui.control.ToggleButton
        RemoteSiteList                  matlab.ui.control.ListBox
        SingleSiteList                  matlab.ui.control.ListBox
        Process_Dec_Panel               matlab.ui.container.Panel
        CheckBox125Hz                   matlab.ui.control.CheckBox
        CheckBox7_8125Hz                matlab.ui.control.CheckBox
        CheckBox15_625Hz                matlab.ui.control.CheckBox
        CheckBox1000Hz                  matlab.ui.control.CheckBox
        CheckBox62_5Hz                  matlab.ui.control.CheckBox
        CheckBox31_25Hz                 matlab.ui.control.CheckBox
        CheckBox250Hz                   matlab.ui.control.CheckBox
        CheckBox500Hz                   matlab.ui.control.CheckBox
        OptionsPanel                    matlab.ui.container.Panel
        NotchFilterUserCheckBox         matlab.ui.control.CheckBox
        ReplaceByCheckBox               matlab.ui.control.CheckBox
        ReplaceBxCheckBox               matlab.ui.control.CheckBox
        EqualizeEDIFreqCheckBox         matlab.ui.control.CheckBox
        ReplaceMagChannelCheckBox       matlab.ui.control.CheckBox
        SmoothHighCheckBox              matlab.ui.control.CheckBox
        FlipByCheckBox                  matlab.ui.control.CheckBox
        SwapMagChannelsCheckBox         matlab.ui.control.CheckBox
        FlipBxCheckBox                  matlab.ui.control.CheckBox
        SmoothLowCheckBox               matlab.ui.control.CheckBox
        HzFilterCheckBox                matlab.ui.control.CheckBox
        HarmonicsCheckBox_2             matlab.ui.control.CheckBox
        CoherencePresortingCheckBox     matlab.ui.control.CheckBox
        ProcessButton                   matlab.ui.control.Button
        TimesyncEditField               matlab.ui.control.NumericEditField
        TimesyncEditFieldLabel          matlab.ui.control.Label
        DistancetoRemoteEditField       matlab.ui.control.NumericEditField
        DistancetoRemoteEditFieldLabel  matlab.ui.control.Label
        kmsLabel                        matlab.ui.control.Label
        hrsLabel                        matlab.ui.control.Label
        RemoteReferenceDropDown         matlab.ui.control.DropDown
        RemoteReferenceDropDownLabel    matlab.ui.control.Label
        StationtoProcessDropDown        matlab.ui.control.DropDown
        StationtoProcessDropDownLabel   matlab.ui.control.Label
        Map                             matlab.ui.container.Panel
        ViewEDIsTab                     matlab.ui.container.Tab
        EDI_Tree                        matlab.ui.container.Tree
        ViewingOptionsLabel             matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        YAxis_MaxEditField              matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        YAxis_MinEditField              matlab.ui.control.NumericEditField
        MinEditFieldLabel_2             matlab.ui.control.Label
        AppResistivityLabel             matlab.ui.control.Label
        Phase_ButtonGroup               matlab.ui.container.ButtonGroup
        Phase_90_Button                 matlab.ui.control.RadioButton
        Phase_180_Button                matlab.ui.control.RadioButton
        Phase_360_Button                matlab.ui.control.RadioButton
        PhaseLabel                      matlab.ui.control.Label
        XAxis_MaxEditField              matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        XAxis_MinEditField              matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        PeriodsLabel                    matlab.ui.control.Label
        EDI_YYCheckBox                  matlab.ui.control.CheckBox
        EDI_XXCheckBox                  matlab.ui.control.CheckBox
        EDI_YXCheckBox                  matlab.ui.control.CheckBox
        EDI_XYCheckBox                  matlab.ui.control.CheckBox
        EDI_display                     matlab.ui.container.Panel
        SelectEDIfilesLabel             matlab.ui.control.Label
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                                                                                %
    %                 ----  Copyright (c) 2019 Ben Vincent Kay  ----                 %
    %                 ----      The University of Adelaide      ----                 %
    %                 ----      ben.kay at adelaide edu au      ----                 %
    %                 ----          MT_FieldApp_2022a           ----                 %
    %                                                                                %
    %  Permission is hereby granted, free of charge, to any person obtaining a copy  %
    %  of this software and associated documentation files (the "Software"), to deal %
    %    in the Software without restriction, subject to the following conditions:   %
    %                                                                                %
    %   The above copyright notice and this permission notice shall be included in   %
    %               all copies or substantial portions of the Software.              %
    %                                                                                %
    %         The Software is provided "as is", without warranty of any kind.        %
    %                                                                                %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    
    properties (Access = public)
        % This list contains global variables/properties that can be called and
        % changed by any function/callback (eg. app.Bx will call the Bx property)
        Property             % Description
        DataLoaded           % File Matrix that is loaded
        folder_name
        filename
        filepath
        Frequency            % Recorded frequency for observed data
        PlotOption           % Which plot option for the PowerSpectra is selected
        ChannelSelected      % Is Bx+Ey or By+Ex selected
        NotchFreq            % Matrix of Notch Frequencies called from user entered values
        DeciFactor           % Decimation factor step 1
        DeciFactor2          % Decimation factor step 2
        dateT
        a
        b
        DeciDateT            % Decimated date array
        SurveyData
        
        % 2020 rewrite of MT field app
        t_Bx
        t_By
        t_Bz
        t_Ex
        t_Ey
        SampleRate
        DateNumber
        DateTime
        F_Bx
        F_By
        F_Ex
        F_Ey
        SSImported_Data   % Imported SS data
        RRImported_Data   % Imported RR data
        MagReplaceImported_Data
        Data_Type_SS       % SS Data Type: 0 = Binary; 1 = Ascii
        Data_Type_RR       % RR Data Type: 0 = Binary; 1 = Ascii
        Data_Type_MR       % RR Data Type: 0 = Binary; 1 = Ascii

        SSFile_List       % List of files selected from the SS station file list
        RRFile_List       % List of files selected from the RR station file list
        MagReplaceFile_List
        Process_DATA      %
        SSDate_Time       % Date Time for SS data - used for matching SS with RR
        RRDate_Time       % Date Time for RR data - used for matching SS with RR
        MagReplaceDate_Time
        BatchNumbers      % Number of times to run batch
        BatchList         % Batch List structure
        EDI_data
        EDI_nFreq
        EDI_header
        EDI_tableNames
        EDI_SearchKEY

        %2022 rewrite of MT app
        Remote_ListBox
        MagReplace_ListBox
        EDI_view_box
        Decimated_Bx
        Decimated_By
        Decimated_Ex
        Decimated_Ey
        Decimated_Rx
        Decimated_Ry
    end
    
    properties (Access = private)
        ax;    % for the axes
        l;     % for the line
        base_map;
    end
    
    methods (Access = private)
        
        % Function that responds to the movement of the sliders
        function lMoving(app, source)
            % Always keep the position rounded to 1 decimal place
            
            source.Position(1,1) = round(source.Position(1,1),1);
            %disp(mat2str(round(source.Position(1,1),1)));
            %app.FromEditField.Value = app.l((1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            %disp(mat2str(round(source.Position(2,1),1)));
            %disp(app.l.Position(1,1))
            %disp(app.l.Position(2,1))
            app.StartSpinner.Value = app.l.Position(1,1);
            app.FinishSpinner.Value = app.l.Position(2,1);
            
            app.StartEditField.Value = char(datetime(double(app.StartSpinner.Value),'ConvertFrom','posixtime'));
            app.FinishEditField.Value = char(datetime(double(app.FinishSpinner.Value),'ConvertFrom','posixtime'));
            % Add any other action here triggered by the movement of the sliders
            SelectData_Slide(app)
        end
        
        function results = ImportData(app,File2Load,FileFormat,LoadMessage,ProcessType,RR_Mag)
            % File2Load: file to load, gets sent by calling the function with the file name
            % File Format: Binary = 0; ASCII = 1; JSON = 2;
            % LaodMessage: Pop up message in uiwaitbar
            
            updateLog(app,append('Import: ',char(File2Load)),0) % update log with file loading
            fID = fopen(char(File2Load));                       % open file to import
            
            % Import funtion for BINARY file
            if FileFormat == 0            % 0 for Binary
                line = fgetl(fID);        % Start at first line of file
                
                % Load Binary Header Data
                LoadMessage.Message = 'Loading Header data';
                header = struct('box_number',str2double(line(end-3:end)));
                for i = 1:28                                    % loop through first 28 lines of the binary header
                    line = fgetl(fID);                          % get line number
                    if ~contains(line,'=')                      % if line contains = then continue
                        continue
                    end
                    tokens = split(strrep(line,'%',''),' = ');  %
                    header.(tokens{1}) = str2double(tokens{2}); %
                end
                
                % Load Binary Time Data
                LoadMessage.Message = 'Loading time data';
                fseek(fID,1024,'bof');
                t = fread(fID,Inf,'ulong',26);
                fseek(fID,1028,'bof');
                FracSec = fread(fID,Inf,'uint16',28);
                app.SampleRate = max(FracSec)+1;
                app.DateTime = datetime(t,'ConvertFrom','posixTime','Format','yyyy mm dd HH MM SS');
                [Y,M,D,H,MN,S] = datevec(app.DateTime);
                FracSec = S+(FracSec/app.SampleRate);
                app.DateNumber = datenum(Y,M,D,H,MN,FracSec);
                app.DateTime = datetime(Y,M,D,H,MN,FracSec);
                DateString = datestr(app.DateNumber(1,:));
                DateFormat = 'yyyy mm dd HH MM SS';
                
                clear Y M D H MN S i line tokens
                
                if ProcessType == 1 || ProcessType == 2 || ProcessType == 3
                    LoadMessage.Message = 'Loading Bx...';
                    fseek(fID,1030,'bof');
                    app.t_Bx = timeseries((fread(fID,Inf,'long',26) * header.Kmx + header.Ax),t,'name','Bx');
                    
                    LoadMessage.Message = 'Loading By...';
                    fseek(fID,1034,'bof');
                    app.t_By = timeseries((fread(fID,Inf,'long',26) * header.Kmy + header.Ay),t,'name','By');

%                     LoadMessage.Message = 'Loading Bz...';
%                     fseek(fID,1038,'bof');
%                     app.t_Bz = timeesries((fread(fID,Inf,'long',26) * header.Kmz + header.Az),t,'name','Bz');
                end
                
                if RR_Mag == 0
                    if ProcessType == 1 || ProcessType == 2 || ProcessType == 3 || ProcessType == 4 || ProcessType == 5
                        LoadMessage.Message = 'Loading Ex...';
                        fseek(fID,1042,'bof');
                        app.t_Ex = timeseries((fread(fID,Inf,'long',26) * header.Ke1 + header.Ae1),t,'name','Ex');

                        LoadMessage.Message = 'Loading Ey...';
                        fseek(fID,1046,'bof');
                        app.t_Ey = timeseries((fread(fID,Inf,'long',26) * header.Ke2 + header.Ae2),t,'name','Ey');
                    end
                end

                % Import function for ASCII file
            elseif FileFormat == 1 % 1 for ASCII
                if ProcessType == 1 || ProcessType == 2 || ProcessType == 3
                    if RR_Mag == 0
                        formatSpec = '%f %f %f %f %f %f %f %f %f %f %f \r';   % YYYY MM DD hh mm ss ms Bx By Ex Ey
                        ImportData = zeros(0, 11);

                    elseif RR_Mag == 1
                        formatSpec = '%f %f %f %f %f %f %f %f %f %*f %*f \r'; % YYYY MM DD hh mm ss ms Bx By
                        ImportData = zeros(0, 9);

                    end

                elseif ProcessType == 4 || ProcessType == 5
                    formatSpec = '%f %f %f %f %f %f %f %f %f %*f %f \r'; % YYYY MM DD hh mm ss ms Ex Ey
                    ImportData = zeros(0, 9);

                end

                LoadMessage.Message = 'Loading ASCII Data';
                
                while ~feof(fID)
                    ImDdata = textscan(fID,formatSpec);
                    ImportData = [ImportData; [ImDdata{:}]];
                end
                app.SampleRate = max(ImportData(:,7))+1;
                
                t = 0:(size(ImportData(:,1),1)-1);    % set the time variable from 0 to length of file
                t = t/app.SampleRate;         % in seconds and correct for Frequency rate
                
                if ProcessType == 1 || ProcessType == 2 || ProcessType == 3
                    if RR_Mag == 0
                        app.t_Bx = timeseries(ImportData(:,8),t,'name','Bx');
                        app.t_By = timeseries(ImportData(:,9),t,'name','By');
                        app.t_Ex = timeseries(ImportData(:,10),t,'name','Ex');
                        app.t_Ey = timeseries(ImportData(:,11),t,'name','Ey');

                    elseif RR_Mag == 1
                        app.t_Bx = timeseries(ImportData(:,8),t,'name','Bx');
                        app.t_By = timeseries(ImportData(:,9),t,'name','By');

                    end
                elseif ProcessType == 4 || ProcessType == 5
                    app.t_Ex = timeseries(ImportData(:,8),t,'name','Ex');
                    app.t_Ey = timeseries(ImportData(:,9),t,'name','Ey');

                end

                FracSec = (ImportData(:,6)+(ImportData(:,7)/app.SampleRate));
                app.DateNumber = datenum(ImportData(:,1),ImportData(:,2),ImportData(:,3),ImportData(:,4),ImportData(:,5),FracSec);
                app.DateTime = datetime(ImportData(:,1),ImportData(:,2),ImportData(:,3),ImportData(:,4),ImportData(:,5),FracSec);
                DateString = datestr(app.DateNumber(1,:));
                DateFormat = 'yyyy mm dd HH MM SS';
                
%                 fclose(fID);
            end

            if ProcessType == 1 || ProcessType == 2 || ProcessType == 3
                if RR_Mag == 0
                    app.t_Bx.TimeInfo.Format = DateFormat;
                    app.t_Bx.TimeInfo.StartDate = DateString;
                    app.t_Bx = setuniformtime(app.t_Bx,'Interval',1/app.SampleRate);

                    app.t_By.TimeInfo.Format = DateFormat;
                    app.t_By.TimeInfo.StartDate = DateString;
                    app.t_By = setuniformtime(app.t_By,'Interval',1/app.SampleRate);

                    app.t_Ex.TimeInfo.Format = DateFormat;
                    app.t_Ex.TimeInfo.StartDate = DateString;
                    app.t_Ex = setuniformtime(app.t_Ex,'Interval',1/app.SampleRate);

                    app.t_Ey.TimeInfo.Format = DateFormat;
                    app.t_Ey.TimeInfo.StartDate = DateString;
                    app.t_Ey = setuniformtime(app.t_Ey,'Interval',1/app.SampleRate);
                
                elseif RR_Mag == 1
                    app.t_Bx.TimeInfo.Format = DateFormat;
                    app.t_Bx.TimeInfo.StartDate = DateString;
                    app.t_Bx = setuniformtime(app.t_Bx,'Interval',1/app.SampleRate);

                    app.t_By.TimeInfo.Format = DateFormat;
                    app.t_By.TimeInfo.StartDate = DateString;
                    app.t_By = setuniformtime(app.t_By,'Interval',1/app.SampleRate);

                end
            elseif ProcessType == 4 || ProcessType == 5
                app.t_Ex.TimeInfo.Format = DateFormat;
                app.t_Ex.TimeInfo.StartDate = DateString;
                app.t_Ex = setuniformtime(app.t_Ex,'Interval',1/app.SampleRate);

                app.t_Ey.TimeInfo.Format = DateFormat;
                app.t_Ey.TimeInfo.StartDate = DateString;
                app.t_Ey = setuniformtime(app.t_Ey,'Interval',1/app.SampleRate);

            end
            fclose(fID);

        end
        
        function results = updateLog(~,yourMsg,timeLog)
            fid = fopen(fullfile('LogFile.txt'), 'a');
            if fid == -1
                error('Cannot open log file.');
            end
            if timeLog == 0
                fprintf(fid, '%s: %s\n', datestr(now, 0), yourMsg);
            elseif timeLog == 1
                fprintf(fid, '     %s\n', yourMsg);
            elseif timeLog == 2 % for displaying the notch filter
                for x= 1:(numel(yourMsg)/2)
                    fprintf(fid, '          %s   to   %s   (Hz)\n', yourMsg(x),yourMsg(x+(numel(yourMsg)/2)));
                end
            elseif timeLog == 3
                fprintf(fid, '     %s  to  %s  (Hz)\n', yourMsg);
            end
            fclose(fid);
        end
        
        function results = PlotTimeSeries(app)
            % create tiled layout in timeseries panel
            t = tiledlayout(app.Panel_TimeSeries,4,1,'TileSpacing','compact','Padding','Compact');
            
            ax1 = nexttile(t); % tile plotting
            line(ax1,app.DateTime,app.t_Bx.Data,'Color',[0.27 0.87 1],'LineWidth',0.8); % plot line in current tile
            ax1.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax1.Toolbar = [];
            ax1.XColor = [0.8 0.8 0.8]; % change x axis color
            ax1.YColor = [0.8 0.8 0.8]; % change y axis color
            ax1.Color = [0.149 0.1961 0.2196]; % change background color
            ax1.XAxis.Visible = 'off';
            ax1.YLabel.String = 'nT';
            title(ax1,'Bx','Color',[0.8 0.8 0.8],'FontWeight','bold');
            
            ax2 = nexttile(t);
            line(ax2,app.DateTime,app.t_By.Data,'Color',[0.27 0.87 1],'LineWidth',0.8);
            ax2.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax2.Toolbar = [];
            ax2.XColor = [0.8 0.8 0.8];
            ax2.YColor = [0.8 0.8 0.8];
            ax2.Color = [0.149 0.1961 0.2196];
            ax2.YLabel.String = 'nT';
            ax2.XAxis.Visible = 'off';
            title(ax2,'By','Color',[0.8 0.8 0.8],'FontWeight','bold');
            
            ax3 = nexttile(t);
            line(ax3,app.DateTime,app.t_Ex.Data,'Color',[0.97 0.42 0.42],'LineWidth',0.8);
            ax3.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax3.Toolbar = [];
            ax3.XColor = [0.8 0.8 0.8];
            ax3.YColor = [0.8 0.8 0.8];
            ax3.Color = [0.149 0.1961 0.2196];
            ax3.YLabel.String = 'mV';
            ax3.XAxis.Visible = 'off';
            title(ax3,'Ex','Color',[0.8 0.8 0.8],'FontWeight','bold');
            
            ax4 = nexttile(t);
            line(ax4,app.DateTime,app.t_Ey.Data,'Color',[0.97 0.42 0.42],'LineWidth',0.8);
            ax4.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax4.Toolbar = [];
            ax4.XColor = [0.8 0.8 0.8];
            ax4.YColor = [0.8 0.8 0.8];
            ax4.Color = [0.149 0.1961 0.2196];
            ax4.YLabel.String = 'mV';
            title(ax4,'Ey','Color',[0.8 0.8 0.8],'FontWeight','bold');
            
            axis ([ax1,ax2,ax3,ax4],'tight'); % set axis scale to extent of data
            linkaxes([ax1,ax2,ax3,ax4],'x'); % link selected x axis together
        end
        
        function results = Detrend_Data(app)
            app.t_Bx.Data = detrend(app.t_Bx.Data,'linear'); % removing of best straight-line fit from data
            app.t_By.Data = detrend(app.t_By.Data,'linear');
            app.t_Ex.Data = detrend(app.t_Ex.Data,'linear');
            app.t_Ey.Data = detrend(app.t_Ey.Data,'linear');
            
            app.t_Bx.Data = detrend(app.t_Bx.Data,'constant'); % removes the mean value from data
            app.t_By.Data = detrend(app.t_By.Data,'constant');
            app.t_Ex.Data = detrend(app.t_Ex.Data,'constant');
            app.t_Ey.Data = detrend(app.t_Ey.Data,'constant');
        end
        
        function results = PlotPowerSeries(app)
            % Call this function to plot the power spectra in the timeseries tab
            
            % Create a tiled layout, with 2 x 2 tiles, with compact spacing between them
            t = tiledlayout(app.Panel_PowerSpectra,2,2,'TileSpacing','Compact','Padding','Compact');
            
            % Plot Welch or Spectrogram depending on what is selected
%                 case 'Spectrogram'
%                     ax1 = nexttile(t);
%                     [s,f,t1] = spectrogram(app.t_Bx.Data,512,50,512,app.SampleRate);
%                     imagesc(ax1,t1,f,log(abs(s)));
%                     ax1.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
%                     ax1.Toolbar = [];
%                     ax1.YLim = [0 (app.SampleRate/2)];
%                     ax1.YLabel.String = 'Bx Frequency (Hz)';
%                     ax1.XLabel.String = 'Time (s)';
%                     colorbar(ax1, 'Color',[0.8 0.8 0.8]);
%                     
%                     ax2 = nexttile(t);
%                     [s,f,t1] = spectrogram(app.t_By.Data,512,50,512,app.SampleRate);
%                     imagesc(ax2,t1,f,log(abs(s)));
%                     ax2.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
%                     ax2.Toolbar = [];
%                     ax2.YLim = [0 (app.SampleRate/2)];
%                     ax2.YLabel.String = 'By Frequency (Hz)';
%                     ax2.XLabel.String = 'Time (s)';
%                     colorbar(ax2, 'Color',[0.8 0.8 0.8]);
%                     
%                     ax3 = nexttile(t);
%                     [s,f,t1] = spectrogram(app.t_Ex.Data,512,50,512,app.SampleRate);
%                     imagesc(ax3,t1,f,log(abs(s)));
%                     ax3.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
%                     ax3.Toolbar = [];
%                     ax3.YLim = [0 (app.SampleRate/2)];
%                     ax3.YLabel.String = 'Ex Frequency (Hz)';
%                     ax3.XLabel.String = 'Time (s)';
%                     colorbar(ax3, 'Color',[0.8 0.8 0.8]);
%                     
%                     ax4 = nexttile(t);
%                     [s,f,t1] = spectrogram(app.t_Ey.Data,512,50,512,app.SampleRate);
%                     imagesc(ax4,t1,f,log(abs(s)));
%                     ax4.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
%                     ax4.Toolbar = [];
%                     ax4.YLim = [0 (app.SampleRate/2)];
%                     ax4.YLabel.String = 'Ey Frequency (Hz)';
%                     ax4.XLabel.String = 'Time (s)';
%                     colorbar(ax4, 'Color',[0.8 0.8 0.8]);

ax1 = nexttile(t);
[pxx,f] = pwelch(app.t_Bx.Data,[],[],[],app.SampleRate);
line(ax1,f,10*log10(pxx),'Color',[0.27 0.87 1], 'LineWidth',0.8);
ax1.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
ax1.Toolbar = [];
ax1.Color = [0.149 0.1961 0.2196];
ax1.XLabel.String = 'Frequency (Hz)';
ax1.YLabel.String = 'Power/frequency (dB/Hz)';
title(ax1,'Bx','Color',[0.8 0.8 0.8],'FontWeight','bold');


ax2 = nexttile(t);
[pxx,f] = pwelch(app.t_By.Data,[],[],[],app.SampleRate);
line(ax2,f,10*log10(pxx),'Color',[0.27 0.87 1], 'LineWidth',0.8);
ax2.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
ax2.Toolbar = [];
ax2.Color = [0.149 0.1961 0.2196];
ax2.XLabel.String = 'Frequency (Hz)';
ax2.YLabel.String = 'Power/frequency (dB/Hz)';
title(ax2,'By','Color',[0.8 0.8 0.8],'FontWeight','bold');

ax3 = nexttile(t);
[pxx,f] = pwelch(app.t_Ex.Data,[],[],[],app.SampleRate);
line(ax3,f,10*log10(pxx),'Color',[0.97 0.42 0.42], 'LineWidth',0.8);
ax3.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
ax3.Toolbar = [];
ax3.Color = [0.149 0.1961 0.2196];
ax3.XLabel.String = 'Frequency (Hz)';
ax3.YLabel.String = 'Power/frequency (dB/Hz)';
title(ax3,'Ex','Color',[0.8 0.8 0.8],'FontWeight','bold');

ax4 = nexttile(t);
[pxx,f] = pwelch(app.t_Ey.Data,[],[],[],app.SampleRate);
line(ax4,f,10*log10(pxx),'Color',[0.97 0.42 0.42], 'LineWidth',0.8);
ax4.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
ax4.Toolbar = [];
ax4.Color = [0.149 0.1961 0.2196];
ax4.XLabel.String = 'Frequency (Hz)';
ax4.YLabel.String = 'Power/frequency (dB/Hz)';
title(ax4,'Ey','Color',[0.8 0.8 0.8],'FontWeight','bold');


ax1.XColor = [0.8 0.8 0.8];
ax1.YColor = [0.8 0.8 0.8];
ax2.XColor = [0.8 0.8 0.8];
ax2.YColor = [0.8 0.8 0.8];
ax3.XColor = [0.8 0.8 0.8];
ax3.YColor = [0.8 0.8 0.8];
ax4.XColor = [0.8 0.8 0.8];
ax4.YColor = [0.8 0.8 0.8];
        end


        function results = FilterData(app)
            value = app.HzFilterCheckBox.Value;
            if value == 1
                FreqNotch = [49 51];
                Harmonics = app.HarmonicsCheckBox_2.Value;
                if Harmonics == 1
                    FreqNotch = [FreqNotch; 99 101; 149 151; 199 201; 249 251; 299 301; 349 351; 399 401; 449 451];
                end

            elseif value == 0
                FreqNotch = (app.UITable.Data);  % get the data entered in the notch table
                FreqNotch = cell2mat(FreqNotch); % convert table cell to matrix
            end

%             updateLog(app,'Filtering data',1)

            disp('Notch Filter set at:');
            disp(FreqNotch)

            value = app.NotchFilterUserCheckBox.Value;
            if value == 1
                value = app.FilterforDropDown.Value;
                if strcmpi(value,'All channels')
                    app.F_Bx = idealfilter(app.Process_DATA(:,1),FreqNotch,'notch'); % notch filter each
                    app.F_By = idealfilter(app.Process_DATA(:,2),FreqNotch,'notch'); % channel
                    app.F_Ex = idealfilter(app.Process_DATA(:,3),FreqNotch,'notch');
                    app.F_Ey = idealfilter(app.Process_DATA(:,4),FreqNotch,'notch');

                elseif strcmpi(value,'Bx Ey')
                    app.F_Bx = idealfilter(app.Process_DATA(:,1),FreqNotch,'notch');
                    app.F_By = app.Process_DATA(:,2);
                    app.F_Ex = app.Process_DATA(:,3);
                    app.F_Ey = idealfilter(app.Process_DATA(:,4),FreqNotch,'notch');

                elseif strcmpi(value,'By Ex')
                    app.F_Bx = app.Process_DATA(:,1);
                    app.F_By = idealfilter(app.Process_DATA(:,2),FreqNotch,'notch');
                    app.F_Ex = idealfilter(app.Process_DATA(:,3),FreqNotch,'notch');
                    app.F_Ey = app.Process_DATA(:,4);
                end
            end

%             value = app.RemovePeriodocityCheckBox.Value;   % If remove periodocity check box has been ticked then ...
%             if value
%                 windowLength = app.PeriodPulsesEditField.Value * app.SampleRate;   % total amount of data points between period pulse (period pulse in Seconds * freq)
%                 
%                 dataIdx = [1 : windowLength: numel(app.t_Bx.data), numel(app.t_Bx.data)+1]';  % create array from 1 to total datalength + 1, increasing by # data points between pulses
%                 
%                 ExDATA = zeros(windowLength,length(dataIdx)-1);   % preallocate to speed things up
%                 EyDATA = zeros(windowLength,length(dataIdx)-1);   % making an array of 0's with # rows from windowlength; # columns by dataIdx-1
%                 BxDATA = zeros(windowLength,length(dataIdx)-1);
%                 ByDATA = zeros(windowLength,length(dataIdx)-1);
%                 
%                 for chunkIdx = 1 : length(dataIdx)-1    % spliting each channel into seperate columns by the windowlength
%                     chunkData = app.F_Ex.data(dataIdx(chunkIdx) : dataIdx(chunkIdx+1)-1,:);
%                     ExDATA(1:numel(chunkData),chunkIdx) = chunkData;
%                     chunkData = app.F_Ey.data(dataIdx(chunkIdx) : dataIdx(chunkIdx+1)-1,:);
%                     EyDATA(1:numel(chunkData),chunkIdx) = chunkData;
%                     chunkData = app.F_Bx.data(dataIdx(chunkIdx) : dataIdx(chunkIdx+1)-1,:);
%                     BxDATA(1:numel(chunkData),chunkIdx) = chunkData;
%                     chunkData = app.F_By.data(dataIdx(chunkIdx) : dataIdx(chunkIdx+1)-1,:);
%                     ByDATA(1:numel(chunkData),chunkIdx) = chunkData;
%                 end
%                 ExMed = median(ExDATA,2);  % get the median for each channel from total amount of pulses
%                 EyMed = median(EyDATA,2);
%                 BxMed = median(BxDATA,2);
%                 ByMed = median(ByDATA,2);
%                 
%                 ExDATA = ExDATA - ExMed;  % remove the median pulse from each channel column
%                 EyDATA = EyDATA - EyMed;
%                 BxDATA = BxDATA - BxMed;
%                 ByDATA = ByDATA - ByMed;
%                 
%                 ExDATA = reshape(ExDATA,[],1);  % reshape data from array to single vector column
%                 EyDATA = reshape(EyDATA,[],1);
%                 BxDATA = reshape(BxDATA,[],1);
%                 ByDATA = reshape(ByDATA,[],1);
%                 
%                 app.F_Bx = BxDATA;  % set the filtered data to data with median periodicity removed
%                 app.F_Ey = EyDATA;
%                 app.F_By = ByDATA;
%                 app.F_Ex = ExDATA;
%                 
%                 t = (0:(size(app.F_Bx,1)-1))/app.SampleRate;
%                 app.F_Bx = timeseries(app.F_Bx,t);
%                 app.F_By = timeseries(app.F_By,t);
%                 app.F_Ex = timeseries(app.F_Ex,t);
%                 app.F_Ey = timeseries(app.F_Ey,t);
%             end
            app.FilteredCheckBox.Value = 1;  % if filtered set invisible check box to 1
        end
        
        
        function results = PlotFilter(app)
            try % if no data has been filtered then just plot unfiltered timeseries
                bx = app.F_Bx.Data;
                by = app.F_By.Data;
                ex = app.F_Ex.Data;
                ey = app.F_Ey.Data;
            catch
                bx = app.t_Bx.Data;
                by = app.t_By.Data;
                ex = app.t_Ex.Data;
                ey = app.t_Ey.Data;
            end
            
            try
                X1 = ax1.XLim; % get x & y axis values
                %X1 = ax2.XLim;
            catch
            end
            t = tiledlayout(app.Panel_TimeSeries_Filt,4,1,'TileSpacing','none','Padding','tight');
            
            ax1 = nexttile(t);
            line(ax1,app.DateTime,bx,'Color',[0.27 0.81 1],'LineWidth',0.8);
            ax1.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax1.Toolbar = [];
            ax1.YColor = [0.8 0.8 0.8];
            ax1.Color = [0.149 0.1961 0.2196];
            ax1.YLabel.String = '(nT)';
            ax1.XAxis.Visible = 'off';
            title(ax1,'Bx','Color',[0.8 0.8 0.8],'FontWeight','bold');
            
            ax2 = nexttile(t);
            line(ax2,app.DateTime,by,'Color',[0.27 0.81 1],'LineWidth',0.8);
            ax2.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax2.Toolbar = [];
            ax2.YColor = [0.8 0.8 0.8];
            ax2.Color = [0.149 0.1961 0.2196];
            ax2.YLabel.String = '(nT)';
%             ax2.XLabel.String = 'Time (UTC)';
            ax2.XAxis.Visible = 'off';
            title(ax2,'By','Color',[0.8 0.8 0.8],'FontWeight','bold');
            
            ax3 = nexttile(t);
            line(ax3,app.DateTime,ex,'Color',[0.97 0.42 0.42],'LineWidth',0.8);
            ax3.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax3.Toolbar = [];
            ax3.YColor = [0.8 0.8 0.8];
            ax3.Color = [0.149 0.1961 0.2196];
            ax3.YLabel.String = '(mV)';
            ax3.XAxis.Visible = 'off';
            title(ax3,'Ex','Color',[0.8 0.8 0.8],'FontWeight','bold');
            
            ax4 = nexttile(t);
            line(ax4,app.DateTime,ey,'Color',[0.97 0.42 0.42],'LineWidth',0.8);
            ax4.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax4.Toolbar = [];
            ax4.YColor = [0.8 0.8 0.8];
            ax4.XColor = [0.8 0.8 0.8];
            ax4.Color = [0.149 0.1961 0.2196];
            ax4.YLabel.String = '(nT)';
            ax4.XLabel.String = 'Time (UTC)';
            title(ax4,'Ey','Color',[0.8 0.8 0.8],'FontWeight','bold');
            
            
            t2 = tiledlayout(app.Panel_PowerSpectra_Filt,1,2,'TileSpacing','tight','Padding','tight');
            ax5 = nexttile(t2);
            [pxx,f] = pwelch(bx,[],[],[],app.SampleRate);
            line(ax5,f,10*log10(pxx),'Color',[0.27 0.87 1], 'LineWidth',0.8);
            
            [pxx,f] = pwelch(ey,[],[],[],app.SampleRate);
            line(ax5,f,(10*log10(pxx)+(app.OffsetEditField.Value)),'Color',[0.97 0.42 0.42], 'LineWidth',0.8);
            ax5.Color = [0.149 0.1961 0.2196];
            ax5.XLabel.String = 'Bx + Ey Frequency (Hz)';
            ax5.YLabel.String = 'Power/frequency (dB/Hz)';
            ax5.XColor = [0.8 0.8 0.8];
            ax5.YColor = [0.8 0.8 0.8];
            
            ax6 = nexttile(t2);
            [pxx,f] = pwelch(by,[],[],[],app.SampleRate);
            line(ax6,f,10*log10(pxx),'Color',[0.27 0.87 1], 'LineWidth',0.8);
            
            [pxx,f] = pwelch(ex,[],[],[],app.SampleRate);
            line(ax6,f,(10*log10(pxx)+(app.OffsetEditField.Value)),'Color',[0.97 0.42 0.42], 'LineWidth',0.8);
            ax6.Color = [0.149 0.1961 0.2196];
            ax6.XLabel.String = 'By + Ex Frequency (Hz)';
            ax6.YLabel.String = 'Power/frequency (dB/Hz)';
            ax6.XColor = [0.8 0.8 0.8];
            ax6.YColor = [0.8 0.8 0.8];
            
            linkaxes([ax1,ax2,ax3,ax4],'x');
            try
                ax1.XLim = X1; % get x & y axis values
                %ax2.XLim = X1;
            catch
                axis ([ax1,ax2,ax3,ax4],'tight');
            end
            
        end
        
        function results = Decimate_Data(app,Decimate2Freq,DataType,DecimateMessage)
            app.DeciFactor = app.SampleRate/Decimate2Freq;

            DeciRunTimes = ([2   3   8   16   32    64     128; ...
                             500 250 125 62.5 31.25 15.625 7.8125; ...
                             1   2   3   4    5     6      7]);

            [~, runtimes] = find(DeciRunTimes == app.DeciFactor);

            DecimateMessage.Message = 'Decimating Data';
%             updateLog(app,'Decimating data',1)

            % DataType: Original TimeSeries = 0; Filtered TimeSeries = 1;
            if DataType == 0
                % get the time array ready for the timeseries function
                t = 0:(size(app.t_Bx.data,1)-1);
                t = t/app.SampleRate;
                t = decimate(t,app.DeciFactor,'fir');

                for i = 1:runtimes
                    app.Decimated_By = decimate(app.t_By.data,2,'fir');
                    app.Decimated_Ex = decimate(app.t_Ex.data,2,'fir');
                    app.Decimated_Bx = decimate(app.t_Bx.data,2,'fir');
                    app.Decimated_Ey = decimate(app.t_Ey.data,2,'fir');
%                     app.Decimated_Bz = decimate(app.t_Bz.data,2,'fir');
%                     disp('Decimation run time number:')
%                     disp(i)
                end

                % set the timeseries for the decimated data
                app.Decimated_By = timeseries(app.Decimated_By,t);
                app.Decimated_Bx = timeseries(app.Decimated_Bx,t);
                app.Decimated_Ey = timeseries(app.Decimated_Ey,t);
                app.Decimated_Ex = timeseries(app.Decimated_Ex,t);
                app.Decimated_Bz = timeseries(app.Decimated_Bz,t);

            elseif DataType == 1
                % Otherwise decimate the filtered data by first step
                app.Decimated_Bx = app.Process_DATA(:,1);
                app.Decimated_By = app.Process_DATA(:,2);
                app.Decimated_Ex = app.Process_DATA(:,3);
                app.Decimated_Ey = app.Process_DATA(:,4);

                switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                    case 'MT - with Remote'
                        app.Decimated_Rx = app.Process_DATA(:,5);
                        app.Decimated_Ry = app.Process_DATA(:,6);

                    case 'IP - with Remote'
                        app.Decimated_Rx = app.Process_DATA(:,5);
                        app.Decimated_Ry = app.Process_DATA(:,6);
                end
%                 disp('Decimation run time number:')
                for i = 1:runtimes
                    app.Decimated_Bx = decimate(app.Decimated_Bx, 2, 'fir');
                    app.Decimated_By = decimate(app.Decimated_By, 2, 'fir');
                    app.Decimated_Ex = decimate(app.Decimated_Ex, 2, 'fir');
                    app.Decimated_Ey = decimate(app.Decimated_Ey, 2, 'fir');

                    switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                        case 'MT - with Remote'
                            app.Decimated_Rx = decimate(app.Decimated_Rx, 2, 'fir');
                            app.Decimated_Ry = decimate(app.Decimated_Ry, 2, 'fir');

                        case 'IP - with Remote'
                            app.Decimated_Rx = decimate(app.Decimated_Rx, 2, 'fir');
                            app.Decimated_Ry = decimate(app.Decimated_Ry, 2, 'fir');
                    end
                    
%                     disp(i)
%                     disp('Number of rows')
%                     disp(numel(app.Decimated_Bx))
                end                 
            end
        end
        
        function results = Lemi_ConfigFile(app,freq,Site_Name)
            SITE = Site_Name;
            RxType = app.SurveyData.('RxType')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);
            
            LONGITUDE = app.SurveyData.('Longitude')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);
            LATITUDE = app.SurveyData.('Latitude')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);
            LONGITUDE = strcat(num2str(degrees2dm(LONGITUDE),'%0.0f %0.5f'),',N');
            LATITUDE = strcat(num2str(degrees2dm(LATITUDE),'%0.0f %0.5f'),',E');
            ELEVATION = num2str(app.SurveyData.('Elevation')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :),'%0.0f');
            DECLINATION = '0';
            SAMPLING = num2str(1/freq,'%f');

            ExAz = -1;
            EyAz = -1;

            disp('Currently in the LEMI_ConfigFile function')

            switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                case 'MT - with Remote'
%                     ProcessType = 1;
                    NCAHN = '6';
                    value = app.FlipBxCheckBox.Value;
                    if value ==1
                        C1 = '-0.0010000';
                    else
                        C1 = '0.0010000';
                    end

                    value = app.FlipByCheckBox.Value;
                    if value ==1
                        C2 = '-0.0010000';
                    else
                        C2 = '0.0010000';
                    end

                    C3 = num2str(ExAz/app.SurveyData.('ExDipole')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :),'%0.7f');
                    C4 = num2str(EyAz/app.SurveyData.('EyDipole')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :),'%0.7f');
                    R1 = '0.0010000';
                    R2 = '0.0010000';
                case 'MT - Single Site'
%                     ProcessType = 2;
                    NCAHN = '4';
                case 'MT - Remote + Single'
%                     ProcessType = 3;
                    NCAHN = '4';
                    R1 = num2str(ExAz/app.SurveyData.('ExDipole')(strcmp(app.SurveyData.('SiteName'), app.RemoteReferenceDropDown.Value), :),'%0.7f');
                    R2 = num2str(EyAz/app.SurveyData.('EyDipole')(strcmp(app.SurveyData.('SiteName'), app.RemoteReferenceDropDown.Value), :),'%0.7f');
                case 'IP - Single Site'
%                     ProcessType = 4;
                    NCAHN = '4';
                case 'IP - with Remote'
%                     ProcessType = 5;
                    NCAHN = '6';
                    E3 = num2str(ExAz/app.SurveyData.('ExDipole')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :),'%0.7f');
                    E4 = num2str(EyAz/app.SurveyData.('EyDipole')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :),'%0.7f');
                    E1 = num2str(ExAz/app.SurveyData.('ExDipole')(strcmp(app.SurveyData.('SiteName'), app.MagChannelReplaceDropDown.Value), :),'%0.7f');
                    E2 = num2str(EyAz/app.SurveyData.('EyDipole')(strcmp(app.SurveyData.('SiteName'), app.MagChannelReplaceDropDown.Value), :),'%0.7f');
                    R1 = num2str(ExAz/app.SurveyData.('ExDipole')(strcmp(app.SurveyData.('SiteName'), app.RemoteReferenceDropDown.Value), :),'%0.7f');
                    R2 = num2str(EyAz/app.SurveyData.('EyDipole')(strcmp(app.SurveyData.('SiteName'), app.RemoteReferenceDropDown.Value), :),'%0.7f');
            end

            % Write config file for LEMI-MT
%             fID = strcat('Process_',num2str(freq),'Hz.cfg');
            fID = sprintf('Process_%.0fHz.cfg',freq);

            cfgFile = fullfile(app.P_LEMIDirEditField_2.Value,fID);
            fileID = fopen(cfgFile,'w');
            fprintf(fileID, 'SITE %s\r\n', SITE);
            fprintf(fileID, 'LATITUDE %s\r\n', LATITUDE);
            fprintf(fileID, 'LONGITUDE %s\r\n', LONGITUDE);
            fprintf(fileID, 'ELEVATION %s\r\n', ELEVATION);
            fprintf(fileID, 'DECLINATION %s\r\n', DECLINATION);
            fprintf(fileID, 'SAMPLING %s\r\n\r\n', SAMPLING);
            fprintf(fileID, 'NCHAN %s\r\n', NCAHN);
            value = app.SwapMagChannelsCheckBox.Value; % flip the channels numbers if Swap mag channels is checked
            if value == 1
                fprintf(fileID, '  2   %s 1  l120new.rsp\r\n', C1);
                fprintf(fileID, '  1   %s 1  l120new.rsp\r\n', C2);
            else
                fprintf(fileID, '  1   %s 1  l120new.rsp\r\n', C1);
                fprintf(fileID, '  2   %s 1  l120new.rsp\r\n', C2);
            end
            fprintf(fileID, '  3   %s 1  e000.rsp\r\n', C3);
            fprintf(fileID, '  4   %s 1  e000.rsp\r\n', C4);
            switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                case 'MT - with Remote'
                    fprintf(fileID, '  5   %s 1  l120new.rsp\r\n', R1);
                    fprintf(fileID, '  6   %s 1  l120new.rsp\r\n', R2);
                    fprintf(fileID, 'NREFCH        2');
                case 'IP - with Remote'
                    fprintf(fileID, '  5   %s 1  e000.rsp\r\n', R1);
                    fprintf(fileID, '  6   %s 1  e000.rsp\r\n', R2);
                    fprintf(fileID, 'NREFCH        2');
            end
            %fprintf(fileID, 'NREFCH        2');
            fclose(fileID);
        end
        
        function results = Lemi_Process(app,freq,fileNumber,d)
            lemi = fullfile(app.P_LEMIDirEditField_2.Value,'lemimt.exe');
            SS = app.StationtoProcessDropDown.Value;
            RR = app.RemoteReferenceDropDown.Value;
            MG = app.MagChannelReplaceDropDown.Value;

            disp('Currently in LEMI Process')
            disp('Processing at freq')
            disp(freq)

            switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                case 'MT - with Remote'
                    ProcessType = ' -f -r Process_';
%                     SiteName = char(strcat('MT-',SS,'_RR-',RR,'_',num2str(freq),'Hz_',num2str(fileNumber)));
                    SiteName = sprintf('MT-%s_RR-%s_%.0fHz_%d',SS,RR,freq,fileNumber);

                case 'MT - Single Site'
                    ProcessType = ' -f Process_';
                    SiteName = char(strcat('MT-',SS,'_',num2str(freq),'Hz_',num2str(fileNumber)));
                case 'MT - Remote + Single'
                    ProcessType = ' -f -r Process_';
                    SiteName = char(strcat('MT-',SS,'_RR-',RR,'_',num2str(freq),'Hz_',num2str(fileNumber)));
                case 'IP - Single Site'
                    ProcessType = ' -f -r Process_';
                    SiteName = char(strcat('IP_1-',SS,'_RR-',RR,'_',num2str(freq),'Hz_',num2str(fileNumber)));
                case 'IP - with Remote'
                    ProcessType = ' -f -r Process_';
                    SiteName = char(strcat('IP_1-',SS,'_2-',MG,'_RR-',RR,'_',num2str(freq),'Hz_',num2str(fileNumber)));
            end
 
            d.Message = sprintf('Processing: %s',SiteName);
            Lemi_ConfigFile(app,freq,SiteName)
%             command = append(lemi,ProcessType,num2str(freq),'Hz.txt');
            command = sprintf('%s%s%.0fHz.txt',lemi,ProcessType,freq);
            disp('command')
            disp(command)
            updateLog(app,append('Processing input:',char(command)),0)
            status = system(command, '-echo');
            updateLog(app,append('EDI output: ',char(SiteName)),0)

            edi_equal = app.EqualizeEDIFreqCheckBox.Value;
            if edi_equal == 1
%                 FileName = append(SiteName,'.edi');
                FileName = strcat(app.P_WorkingDirEditField.Value,filesep,SiteName,'.edi');

                
                LoadEDIfile(app,FileName)
%                 FileName1=append(SiteName,'new.edi');
                WriteEDIfile(app,FileName)
            end
            
            % move EDI file from root dir to site dir
%             disp('moving EDI file')
%             disp(FileName)
%             disp('to folder ')
%             disp(SS)

            copyfile(strcat(SiteName,'.edi'),char(SS))

        end
        
        function results = Smooth_Processing_Data(app,Export_Freq)
            % apply gaussian smoothing for varying window lengths depending on the export frequency
            
            % small window length for the gaussian smoothing
            if app.SmoothLowCheckBox.Value == 1
                smoothing = [7.8125, 64; 15.625, 32; 31.25, 16; 62.5, 8];

            % large window length for the gaussian smoothing
            elseif app.SmoothHighCheckBox.Value == 1
                smoothing = [7.8125, 128; 15.625, 64; 31.25, 32; 62.5, 16];

            end

            for i = 1:length(smoothing)
                if Export_Freq == smoothing(i,1)
                    updateLog(app,append('Smoothing data window size ',num2str(smoothing(i,2))),0)

                    app.Process_DATA(:,1) =  smoothdata(app.Process_DATA(:,1),'Gaussian',smoothing(i,2));
                    app.Process_DATA(:,2) =  smoothdata(app.Process_DATA(:,2),'Gaussian',smoothing(i,2));
                    app.Process_DATA(:,3) =  smoothdata(app.Process_DATA(:,3),'Gaussian',smoothing(i,2));
                    app.Process_DATA(:,4) =  smoothdata(app.Process_DATA(:,4),'Gaussian',smoothing(i,2));

                    switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                        case 'MT - with Remote'
                            app.Process_DATA(:,5) =  smoothdata(app.Process_DATA(:,5),'Gaussian',smoothing(i,2));
                            app.Process_DATA(:,6) =  smoothdata(app.Process_DATA(:,6),'Gaussian',smoothing(i,2));

                        case 'IP - with Remote'
                            app.Process_DATA(:,5) =  smoothdata(app.Process_DATA(:,5),'Gaussian',smoothing(i,2));
                            app.Process_DATA(:,6) =  smoothdata(app.Process_DATA(:,6),'Gaussian',smoothing(i,2));
                    end
                end
            end
        end

        function results = Export(app,Filter,Decimate,FileType,exportFreq,FileName,ExportMessage)
            % DataType = Style of data to be exported; newly filtered file = 0; data to be processed = 1; Decimated data = 2
            % FileType = File Format; 0 = lemigraph ASCII file; 1 = EDL 4 column format;
            % exportFreq = frequncy the data is being exported at
            %h = waitbar(0, 'Exporting data...', 'Name','Exporting data...');
            
            if FileType == 1 % Export ASCII data for Processing

                % if data needs Filtering
                if Filter == 1 
                    FilterData(app)
                    app.F_Bx = app.Process_DATA(:,1);
                    app.F_By = app.Process_DATA(:,2);
                    app.F_Ex = app.Process_DATA(:,3);
                    app.F_Ey = app.Process_DATA(:,4);
                end

                exportData(:,1) = app.Process_DATA(:,1);
                exportData(:,2) = app.Process_DATA(:,2);
                exportData(:,3) = app.Process_DATA(:,3);
                exportData(:,4) = app.Process_DATA(:,4);

                format = '%12.4f %12.4f %12.3f %12.3f\r';

                switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                    case 'MT - with Remote'
                        exportData(:,5) = app.Process_DATA(:,5);
                        exportData(:,6) = app.Process_DATA(:,6);

                        format = '%12.4f %12.4f %12.3f %12.3f %12.4f %12.4f\r';

                    case 'IP - with Remote'
                        exportData(:,5) = app.Process_DATA(:,5);
                        exportData(:,6) = app.Process_DATA(:,6);

                        format = '%12.4f %12.4f %12.3f %12.3f %12.4f %12.4f\r';
                end

                % If data needs smoothing
                if app.SmoothHighCheckBox.Value == 1 || app.SmoothLowCheckBox.Value == 1
                    Smooth_Processing_Data(app,exportFreq)
                end

                % if data needs Decimating
                if Decimate == 1
                    exportData = [];
                    Decimate_Data(app,exportFreq,1,ExportMessage)
                    exportData(:,1) = app.Decimated_Bx;
                    exportData(:,2) = app.Decimated_By;
                    exportData(:,3) = app.Decimated_Ex;
                    exportData(:,4) = app.Decimated_Ey;
                    switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                        case 'MT - with Remote'
                            exportData(:,5) = app.Decimated_Rx;
                            exportData(:,6) = app.Decimated_Ry;
                        case 'IP - with Remote'
                            exportData(:,5) = app.Decimated_Rx;
                            exportData(:,6) = app.Decimated_Ry;
                    end
                end

                ExportMessage.Message = 'Exporting Data';

                savename = char(strcat(app.P_WorkingDirEditField.Value,filesep,FileName));
                fid = fopen(savename,'a');

                updateLog(app,append('Exporting file ',FileName),0)
                cN = 150;
                dN = numel(exportData(:,1))-1;
                dataIdx = [1 : ceil(dN/cN) : dN, dN+1];

                % convert the datetime to a date vector array
                for chunkIdx = 0 : cN-1
                    ExportMessage.Value = chunkIdx/cN;
%                     disp('Exporting - chunkIdx')
%                     disp(chunkIdx)
                    chunkData = exportData(dataIdx(chunkIdx+1) : dataIdx(chunkIdx+2)-1,:);
                    chunkData = sprintf(format,chunkData')';
                    fwrite(fid,chunkData,'char*1');
                end

                % default export format for four channels
                format = '%12.4f %12.4f %12.3f %12.3f'; 

                % change export format for six channels if remote reference
                % is selected
                switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                    case 'MT - with Remote'
                        format = '%12.4f %12.4f %12.3f %12.3f %12.4f %12.4f';

                    case 'IP - with Remote'
                        format = '%12.4f %12.4f %12.3f %12.3f %12.4f %12.4f';
                end
                
                chunkData = exportData(dN+1,:);
                chunkData = sprintf(format,chunkData')';
                fwrite(fid,chunkData,'char*1');

            elseif FileType == 0 % Exporting file type as an lemigraph ASCII

            elseif FileType == 2 % Exporting file for processing with no RR
                if Decimate == 1 % Decimated data of some type
                    % set decimated data to export
                    ExportMessage.Message = 'Exporting Data';
                    app.DeciFactor = app.SampleRate/exportFreq;

                    savename = char(strcat(app.P_WorkingDirEditField.Value,filesep,FileName));

%                     disp('Process_data all column first 5 rows')
%                     disp(app.Process_DATA(1:5,:))
                                    
                    exportData(:,1) = decimate(app.Process_DATA(:,1),app.DeciFactor,'fir');
                    exportData(:,2) = decimate(app.Process_DATA(:,2),app.DeciFactor,'fir');
                    exportData(:,3) = decimate(app.Process_DATA(:,3),app.DeciFactor,'fir');
                    exportData(:,4) = decimate(app.Process_DATA(:,4),app.DeciFactor,'fir');

                    format = '%12.3f %12.3f %12.3f %12.3f\r';
                    num_columns = 4;

                    switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                        case 'IP - with Remote'
                            exportData(:,5) = decimate(app.Process_DATA(:,5),app.DeciFactor,'fir');
                            exportData(:,6) = decimate(app.Process_DATA(:,6),app.DeciFactor,'fir');
                            num_columns = 6;

%                             disp('this is decimating')

                            format = '%12.3f %12.3f %12.3f %12.3f %12.3f %12.3f\r';
                    end
                    
                    fid = fopen(savename,'Ab');
                    
                elseif Decimate == 0 % export undecimated data
                    exportData(:,1) = app.Process_DATA(:,1);
                    exportData(:,2) = app.Process_DATA(:,2);
                    exportData(:,3) = app.Process_DATA(:,3);
                    exportData(:,4) = app.Process_DATA(:,4);
                    num_columns = 4;
                    switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                        case 'IP - with Remote'
                            exportData(:,5) = app.Process_DATA(:,5);
                            exportData(:,6) = app.Process_DATA(:,6);

%                             disp('this is here')
                            num_columns = 6;

                            format = '%12.3f %12.3f %12.3f %12.3f %12.3f %12.3f\r';
                    end

                    savename = char(strcat(app.P_WorkingDirEditField.Value,filesep,FileName));
                    fid = fopen(savename,'Wb');
                end
                updateLog(app,append('Exporting file ',FileName),0)
                cN = 150;
                dN = numel(exportData(:,num_columns))-1;
                
                dataIdx = [1 : round(dN/cN) : dN, dN+1];
%                 [fpath,name, ~] = fileparts(savename);
%                 savename = fullfile(fpath,strcat(name,'.txt'));

                % convert the datetime to a date vector array
                %                 format = '%12.4f %12.4f %12.3f %12.3f\r';
                for chunkIdx = 0 : cN-1
                    ExportMessage.Value = chunkIdx/cN;
                    chunkData = exportData(dataIdx(chunkIdx+1) : dataIdx(chunkIdx+2)-1,1:num_columns);
                    chunkData = sprintf(format,chunkData')';
                    fwrite(fid,chunkData,'char*1');
                end
                format = '%12.3f %12.3f %12.3f %12.3f';
                switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                    case 'IP - with Remote'
                        format = '%12.3f %12.3f %12.3f %12.3f';
                end
                %format = '%12.4f %12.4f %12.3f %12.3f';
                chunkData = exportData(dN+1,:); % add Bx
                chunkData = sprintf(format,chunkData')';
                fwrite(fid,chunkData,'char*1');

            end
            fclose(fid);
        end


        function results = batchDecimationFrequencies(app)
            val = app.CheckBox1000Hz.Value;
            if val
                app.ProcessFreqList.Data = 1000;
            else
                app.ProcessFreqList.Data = [];
            end
            val = app.CheckBox500Hz.Value;
            if val
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = [oldData; 500];
            else
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = oldData;
            end
            val = app.CheckBox250Hz.Value;
            if val
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = [oldData; 250];
            else
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = oldData;
            end
            val = app.CheckBox125Hz.Value;
            if val
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = [oldData; 125];
            else
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = oldData;
            end
            val = app.CheckBox62_5Hz.Value;
            if val
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = [oldData; 62.5];
            else
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = oldData;
            end
            val = app.CheckBox31_25Hz.Value;
            if val
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = [oldData; 31.25];
            else
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = oldData;
            end
            val = app.CheckBox15_625Hz.Value;
            if val
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = [oldData; 15.625];
            else
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = oldData;
            end
            val = app.CheckBox7_8125Hz.Value;
            if val
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = [oldData; 7.8125];
            else
                oldData = app.ProcessFreqList.Data;
                app.ProcessFreqList.Data = oldData;
            end
        end
        
        
        function results = ProcessSiteList(app)
            % Populate the Station lists in the processing tab
            Data_Type = app.SurveyData.('Data_Type')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);
            Data_Type = char(Data_Type);
            switch Data_Type
                case 'BINARY'
                    extension = strcat(filesep,'*.B423');
                    app.Data_Type_SS = 0;
                    app.Data_Type_RR = 0;
                    app.Data_Type_MR = 0;
                case 'ASCII'
                    extension = strcat(filesep,'*.txt');
                    app.Data_Type_SS = 1;
                    app.Data_Type_RR = 1;
                    app.Data_Type_MR = 1;
                case 'JSON'
                    extension = strcat(filesep,'*.json');
                    app.Data_Type_SS = 2;
                    app.Data_Type_RR = 2;
                    app.Data_Type_MR = 2;
            end

            app.SSFile_List = dir(strcat(app.P_WorkingDirEditField.Value,filesep,app.StationtoProcessDropDown.Value,extension));

            try
                app.SSFile_List = struct2table(app.SSFile_List);
                app.SSFile_List = table2array(app.SSFile_List(:,1));
                app.SSFile_List = cellstr(app.SSFile_List);
%                 disp('SS File List')
%                 disp(app.SSFile_List)
                app.SSFile_List = app.SSFile_List(~contains(app.SSFile_List,{'._'}));
%                 disp('SS File List ._')
%                 disp(app.SSFile_List)
                app.SingleSiteList.Items = (app.SSFile_List);
                app.SingleSiteList.ItemsData = 1:numel(app.SSFile_List);
                app.RemoteSiteList.Items = " ";
                app.MagChannelReplaceList.Items = " ";
            catch
                app.SSFile_List = ('');
                app.SSFile_List  = cellstr(app.SSFile_List);
                app.SingleSiteList.Items = (app.SSFile_List);
                app.SingleSiteList.ItemsData = 1:numel(app.SSFile_List);
            end
            switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                case 'MT - with Remote'
                    try
                        app.RRFile_List = dir(strcat(app.P_WorkingDirEditField.Value,filesep,app.RemoteReferenceDropDown.Value,extension));
                        app.RRFile_List = struct2table(app.RRFile_List);
                        app.RRFile_List = table2array(app.RRFile_List(:,1));
                        app.RRFile_List = cellstr(app.RRFile_List);
                        app.RRFile_List = app.RRFile_List(~contains(app.RRFile_List,{'._'}));
                        app.RemoteSiteList.Items = app.RRFile_List;
                        app.RemoteSiteList.ItemsData = 1:numel(app.RRFile_List);
                    catch
                        app.RRFile_List = ('');
                        app.RRFile_List  = cellstr(app.RRFile_List);
                        app.RemoteSiteList.Items = app.RRFile_List;
                        app.RemoteSiteList.ItemsData = 1:numel(app.RRFile_List);
                    end
            end

            value = app.ReplaceMagChannelCheckBox.Value;
            if value == 1
                try
                    app.MagReplaceFile_List = dir(strcat(app.P_WorkingDirEditField.Value,filesep,app.MagChannelReplaceDropDown.Value,extension));
                    app.MagReplaceFile_List = struct2table(app.MagReplaceFile_List);
                    app.MagReplaceFile_List = table2array(app.MagReplaceFile_List(:,1));
                    app.MagReplaceFile_List = cellstr(app.MagReplaceFile_List);
                    app.MagReplaceFile_List = app.MagReplaceFile_List(~contains(app.MagReplaceFile_List,{'._'}));
                    app.MagChannelReplaceList.Items = app.MagReplaceFile_List;
                    app.MagChannelReplaceList.ItemsData = 1:numel(app.MagReplaceFile_List);

                catch
                    app.MagReplaceFile_List = ('');
                    app.MagReplaceFile_List  = cellstr(app.MagReplaceFile_List);
                    app.MagChannelReplaceList.Items = app.MagReplaceFile_List;
                    app.MagChannelReplaceList.ItemsData = 1:numel(app.MagReplaceFile_List);
                end
            end
            UpdateMap(app)
            
            % grab the file names from the station to process to calculate
            % the timing

            Timing_File_List = dir(strcat(app.P_WorkingDirEditField.Value,filesep,app.StationtoProcessDropDown.Value,extension));
            Timing_File_List = struct2table(Timing_File_List);
            Timing_File_List = table2array(Timing_File_List(:,1));
            Timing_File_List = cellstr(Timing_File_List);

%             disp('Timing File List')
%             disp(Timing_File_List)

            Timing_File_List = Timing_File_List(~contains(Timing_File_List,{'._'}));

%             disp('Timing File List name ._)')
%             disp(Timing_File_List)

            StartTime = split(Timing_File_List(1),".");
            EndTime = split(Timing_File_List(numel(Timing_File_List)),".");

%             disp('Start time')
%             disp(str2num(StartTime{1}))
% 
%             disp('Finish time')
%             disp(str2num(EndTime{1}))

            SlideBar(app,str2num(StartTime{1}),str2num(EndTime{1}))
            %app.SingleSiteList.Value
        end
        
        function results = Batch(app)
            
            app.BatchList(app.BatchNumbers).StationtoProcess = app.StationtoProcessDropDown.Value;
            app.BatchList(app.BatchNumbers).StationSiteList = app.SingleSiteList.Value;
            app.BatchList(app.BatchNumbers).RemoteReferencetoProcess = app.RemoteReferenceDropDown.Value;
            app.BatchList(app.BatchNumbers).RemoteSiteList = app.RemoteSiteList.Value;
            app.BatchList(app.BatchNumbers).NotchFilter50Hz = app.HzFilterCheckBox.Value;
            app.BatchList(app.BatchNumbers).NotchFilterHarmonics = app.HarmonicsCheckBox_2.Value;
            app.BatchList(app.BatchNumbers).NotchFilterUserSelected = app.NotchFilterUserCheckBox.Value;
            app.BatchList(app.BatchNumbers).SmoothingLow = app.SmoothLowCheckBox.Value;
            app.BatchList(app.BatchNumbers).SmoothingHigh = app.SmoothHighCheckBox.Value;
            app.BatchList(app.BatchNumbers).ProcessFreq = app.ProcessFreqList.Data;
            app.BatchList(app.BatchNumbers).RepalceMagChannel = app.ReplaceMagChannelCheckBox.Value;
            app.BatchList(app.BatchNumbers).ReplaceBx = app.ReplaceBxCheckBox.Value;
            app.BatchList(app.BatchNumbers).ReplaceBy = app.ReplaceByCheckBox.Value;
%             app.BatchList(app.BatchNumbers).MagChannelReplaceProcess = app.ReplaceMagChannelCheckBox.Value;
%             app.BatchList(app.BatchNumbers).MagChannelReplaceSiteList = app.MagChannelReplaceList.Value;
            app.BatchList(app.BatchNumbers).SwapMagChannels = app.SwapMagChannelsCheckBox.Value;
            app.BatchList(app.BatchNumbers).FlipBx = app.FlipBxCheckBox.Value;
            app.BatchList(app.BatchNumbers).FlipBy = app.FlipByCheckBox.Value;
            app.BatchList(app.BatchNumbers).EDIequalize = app.EqualizeEDIFreqCheckBox.Value;
            app.BatchList(app.BatchNumbers).NotchFreqUserSelected = app.UITable.Data;
            
            app.BatchNumbers = app.BatchNumbers + 1;

            disp(append('Site ',app.StationtoProcessDropDown.Value,' added to processing queue with ',num2str(numel(app.SingleSiteList.Value)),' files seleced'))
            disp(append('Processing queue now at ',num2str(app.BatchNumbers-1)))
        end

        function results = ProcessBatch(app)
            updateLog(app,'                STARTING NEW PROCESSING BATCH',1)
            for i = 1:length(app.BatchList)
                disp(app.BatchList(i))
                % Select Batch site to process
                app.StationtoProcessDropDown.Value = app.BatchList(i).StationtoProcess;
                
                % Update the Remote Ref dropdown box
                toDelete = app.StationtoProcessDropDown.Value;
                list = app.StationtoProcessDropDown.Items;
                list(strcmp(list, toDelete)) = [];
                app.RemoteReferenceDropDown.Items = list;

                % Select Batch remote site to process
                app.RemoteReferenceDropDown.Value = app.BatchList(i).RemoteReferencetoProcess;

                % update the site lists and map
                ProcessSiteList(app)

                % select the files for batch processing
                app.SingleSiteList.Value = app.BatchList(i).StationSiteList;
                app.RemoteSiteList.Value = app.BatchList(i).RemoteSiteList;

                % update the user selected options
                app.HzFilterCheckBox.Value = app.BatchList(i).NotchFilter50Hz;
                app.HarmonicsCheckBox_2.Value = app.BatchList(i).NotchFilterHarmonics;
                app.NotchFilterUserCheckBox.Value = app.BatchList(i).NotchFilterUserSelected;
                app.SmoothLowCheckBox.Value = app.BatchList(i).SmoothingLow;
                app.SmoothHighCheckBox.Value = app.BatchList(i).SmoothingHigh;

                app.ProcessFreqList.Data = app.BatchList(i).ProcessFreq;
                app.ReplaceMagChannelCheckBox.Value = app.BatchList(i).RepalceMagChannel;
                app.ReplaceBxCheckBox.Value = app.BatchList(i).ReplaceBx;
                app.ReplaceByCheckBox.Value = app.BatchList(i).ReplaceBy;
                
%                 app.MagChannelReplaceDropDown.Value = app.BatchList(i).MagChannelReplaceProcess;
%                 app.MagChannelReplaceList.Value = app.BatchList(i).MagChannelReplaceSiteList;

                app.SwapMagChannelsCheckBox.Value = app.BatchList(i).SwapMagChannels;
                app.FlipBxCheckBox.Value = app.BatchList(i).FlipBx;
                app.FlipByCheckBox.Value = app.BatchList(i).FlipBy;
                app.EqualizeEDIFreqCheckBox.Value = app.BatchList(i).EDIequalize;

                app.UITable.Data = app.BatchList(i).NotchFreqUserSelected;

                updateLog(app,'',1)
                updateLog(app,char(append('                      BATCH ',num2str(i),' of ',num2str(length(app.BatchList)))),1)
                updateLog(app,'',1)

                ProcessData(app)
            end
                        updateLog(app,'                FINISHED PROCESSING BATCH',1)

        end
        
        % The ProcessData function is used called for processing the data
        function results = ProcessData(app)
            % Set the working paths, for the selected data
            WorkingPath = app.P_WorkingDirEditField.Value;
            SSlist = app.SingleSiteList.Items(app.SingleSiteList.Value);
            SS = app.StationtoProcessDropDown.Value;
            SSfiles = strcat(WorkingPath,filesep,SS,filesep,SSlist);

            % update the processing frequencies
            batchDecimationFrequencies(app)
            Process_Batch = app.ProcessFreqList.Data;

            app.Process_DATA = [];
            app.RRImported_Data = [];
            app.SSImported_Data = [];

            % Delete the previous processing ASCII time series data
            for i = 1:length(Process_Batch)
                try
                    delete(sprintf('Process_%.0fHz.txt',Process_Batch(i)))
%                     updateLog(app,append('Cleaning previous processing files: ',string(Process_Batch(i), ' Hz')),1)
                catch
                end
            end

            % update log file, from user selected options
            updateLog(app,'Starting new processing run',0)
            updateLog(app,'',1)
            updateLog(app,append('Processing @ selected frequencies : ',string(Process_Batch), ' Hz'),1)
            updateLog(app,append('Number of selected files: ',num2str(length(SSlist))),1)

            Smooth = app.SmoothLowCheckBox.Value;
            if Smooth == 1
                updateLog(app,'Smoothing (Gaussian) decimated data with small data window',1)
            end
            Smooth = app.SmoothHighCheckBox.Value;
            if Smooth == 1
                updateLog(app,'Smoothing (Gaussian) decimated data with large data window',1)
            end
            Filter = 0;
            value = app.HzFilterCheckBox.Value;
            if value == 1
                Filter = 1;
                value = app.HarmonicsCheckBox_2.Value;
                if value == 1
                    updateLog(app,'Processing with 50 Hz filter with harmonics',3)
                else
                    updateLog(app,'Processing with 50 Hz filter',3)
                end
            end
            value = app.NotchFilterUserCheckBox.Value;
            if value == 1
                updateLog(app,'Processing with user selected notch filter @',1)
                FreqNotch = (app.UITable.Data);  % get the data entered in the notch table
%                 FreqNotch = cell2mat(FreqNotch); % convert table cell to matrix
                updateLog(app,string(FreqNotch),2)

            end


            % set the sample rate from the metadata
            app.SampleRate = app.SurveyData.('SampleRate')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);

            SScount = 0;              % counter for SS files to load
            z = 0;                    % # files loaded check
            f500num = 1;              % File number for 500 Hz
            f250num = 1;              % File number for 250 Hz
            f1000num = 1;             % File number for 1000 Hz
            y1 = 0;                   % end of line check
            Files2Load = 2;           % Load # files at 1000 Hz at a time
            x1 = 0;                   % counter for number of files to load before processing at 500 Hz
            x2 = 0;                   % counter for number of files to load before processing at 250 Hz

            updateLog(app,'',1)

            % Load the first file
            switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                case 'MT - with Remote'
                    ProcessType = 1;
                    updateLog(app,'MT - Remote Reference Processing selected',1)

                    RRlist = app.RemoteSiteList.Items(app.RemoteSiteList.Value);
                    RR = app.RemoteReferenceDropDown.Value;
                    RRfiles = strcat(WorkingPath,filesep,RR,filesep,RRlist);

                    updateLog(app,append('Processing site ',SS,' with site ',RR,' as the remote'),1)
                    updateLog(app,'',1)

                    d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Load RR file: %s',RRlist{1}),"Indeterminate",'on');

                    ImportData(app,RRfiles{1},app.Data_Type_RR,d,ProcessType,1)
                    app.RRImported_Data = [app.RRImported_Data; [app.t_Bx.data app.t_By.data]];
                    app.RRDate_Time = [app.RRDate_Time; [app.DateTime]];

                    Rcount = 1; % counter for number of RR files to load

                case 'MT - Single Site'
                    ProcessType = 2;
                    updateLog(app,'MT - Single Site Processing selected',1)

                    updateLog(app,append('Processing site ',SS,' by itself'),1)
                    updateLog(app,'',1)

                    Rcount = 1; % counter for number of RR files to load

                case 'MT - Remote + Single'
                    ProcessType = 3;
                    updateLog(app,'MT - Remote Reference + Single Site Processing selected',1)

                    RRlist = app.RemoteSiteList.Items(app.RemoteSiteList.Value);
                    RR = app.RemoteReferenceDropDown.Value;
                    RRfiles = strcat(WorkingPath,filesep,RR,filesep,RRlist);

                    updateLog(app,append('Processing site ',SS,' with site ',RR,' as Remote'),1)
                    updateLog(app,'',1)

                    d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Load RR file: %s',RRlist{1}),"Indeterminate",'on');

                    ImportData(app,RRfiles{1},app.Data_Type_RR,d,ProcessType,1) % call the ImportData function
                    app.RRImported_Data = [app.RRImported_Data; [app.t_Bx.data app.t_By.data]]; % only using Ex & Ey channels from the imported data
                    app.RRDate_Time = [app.RRDate_Time; [app.DateTime]]; % timing data from the RR imported data

                    Rcount = 1; % counter for number of RR files to load

                case 'IP - Single Site'
                    ProcessType = 4;
                    updateLog(app,'IP - Single Site Processing selected',1)

                    MagReplacelist = app.MagChannelReplaceList.Items(app.MagChannelReplaceList.Value);
                    MagRep = app.MagChannelReplaceDropDown.Value;
                    MagReplacefiles = strcat(WorkingPath,filesep,MagRep,filesep,MagReplacelist);

                    updateLog(app,append('Processing site ',SS,' with site ',MagRep,' as the adjacent E-fields'),1)
                    updateLog(app,'',1)

                    d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Load 2nd E-Field file: %s',MagReplacelist{1}),"Indeterminate",'on');

                    ImportData(app,MagReplacefiles{1},app.Data_Type_MR,d,ProcessType,0)
                    app.MagReplaceImported_Data = [app.MagReplaceImported_Data; [app.t_Ex.data app.t_Ey.data]];
                    app.MagReplaceDate_Time = [app.MagReplaceDate_Time; [app.DateTime]];

                    Rcount = 1; % counter for number of RR files to load

                case 'IP - with Remote'
                    ProcessType = 5;
                    updateLog(app,'IP - Remote Reference Processing selected',1)

                    MagReplacelist = app.MagChannelReplaceList.Items(app.MagChannelReplaceList.Value);
                    MagRep = app.MagChannelReplaceDropDown.Value;
                    MagReplacefiles = strcat(WorkingPath,filesep,MagRep,filesep,MagReplacelist);

                    RRlist = app.RemoteSiteList.Items(app.RemoteSiteList.Value);
                    RR = app.RemoteReferenceDropDown.Value;
                    RRfiles = strcat(WorkingPath,filesep,RR,filesep,RRlist);
                    
                    updateLog(app,append('Processing site ',SS,' with site ',MagRep,' as the adjacent E-fields, and site ',RR,'as Remote'),0)
                    updateLog(app,'',1)

                    d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Load 2nd E-Field file: %s',MagReplacelist{1}),"Indeterminate",'on');

                    ImportData(app,MagReplacefiles{1},app.Data_Type_MR,d,ProcessType,0)
                    app.MagReplaceImported_Data = [app.MagReplaceImported_Data; [app.t_Ex.data app.t_Ey.data]];
                    app.MagReplaceDate_Time = [app.MagReplaceDate_Time; [app.DateTime]];

                    d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Load RR file: %s',RRlist{1}),"Indeterminate",'on');

                    ImportData(app,RRfiles{1},app.Data_Type_RR,d,ProcessType,0) % call the ImportData function
                    app.RRImported_Data = [app.RRImported_Data; [app.t_Ex.data app.t_Ey.data]]; % only using Ex & Ey channels from the imported data
                    app.RRDate_Time = [app.RRDate_Time; [app.DateTime]]; % timing data from the RR imported data

                    Rcount = 1; % counter for number of RR files to load
            end

%             updateLog(app,'',1)

            for i = 1:length(SSfiles)
                SScount = SScount + 1;

                d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Load SS file: %s',SSlist{SScount}),"Indeterminate",'on');

                % Import single site data, ASCII file, message, single site processing data
                ImportData(app,SSfiles(i),app.Data_Type_SS,d,ProcessType,0)

                if ProcessType == 1 || ProcessType == 2 || ProcessType == 3
                    app.SSImported_Data = [app.SSImported_Data; [app.t_Bx.data app.t_By.data app.t_Ex.data app.t_Ey.data]];
                elseif ProcessType == 4 || ProcessType == 5
                    app.SSImported_Data = [app.SSImported_Data; [app.t_Ex.data app.t_Ey.data]];
                end

                app.SSDate_Time = [app.SSDate_Time; [app.DateTime]];

                % find the start date and time for loaded data
                Orig_Start = app.SSDate_Time(1,:);

                % find the finsih date and time for loaded data
                Orig_Fin = app.SSDate_Time(length(app.SSDate_Time),:);

                if ProcessType == 1 || ProcessType == 3 || ProcessType == 5
                    d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Load RR file: %s',RRlist{Rcount+1}),"Indeterminate",'on');

                    ImportData(app,RRfiles{i+1},app.Data_Type_RR,d,ProcessType,1)

                    if ProcessType == 1 || ProcessType == 3
                        app.RRImported_Data = [app.RRImported_Data; [app.t_Bx.data app.t_By.data]];
                    elseif ProcessType == 5
                        app.RRImported_Data = [app.RRImported_Data; [app.t_Ex.data app.t_Ey.data]];
                    end

%                     disp('RR imported - number of rows')
%                     disp(numel(app.RRImported_Data(:,1)))

                    app.RRDate_Time = [app.RRDate_Time; [app.DateTime]];
                    Rep_StartCell = find(app.RRDate_Time==Orig_Start,1,'first');    % find the start date and time RR data
                    Rep_FinCell = find(app.RRDate_Time==Orig_Fin,1,'first');        % find the finsih date and time RR data
                end

                if ProcessType == 4 || ProcessType == 5
                    d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Load 2nd E-Field file: %s',MagReplacelist{Rcount+1}),"Indeterminate",'on');

                    ImportData(app,MagReplacefiles{i+1},app.Data_Type_MR,d,ProcessType,0)
                    app.MagReplaceImported_Data = [app.MagReplaceImported_Data; [app.t_Ex.data app.t_Ey.data]];

                    app.MagReplaceDate_Time = [app.MagReplaceDate_Time; [app.DateTime]];
                    MagRep_StartCell = find(app.MagReplaceDate_Time==Orig_Start,1,'first');
                    MagRep_FinCell = find(app.MagReplaceDate_Time==Orig_Fin,1,'first');     % find the finsih date and time for loaded data

                end
                
                app.Process_DATA = app.SSImported_Data(1:length(app.SSImported_Data),:);

                switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                    case 'MT - with Remote'
                        app.Process_DATA(:,5) = app.RRImported_Data(Rep_StartCell:Rep_FinCell,1);
                        app.Process_DATA(:,6) = app.RRImported_Data(Rep_StartCell:Rep_FinCell,2);

                    case 'MT - Single Site'

                    case 'MT - Remote + Singe'
                        app.Process_DATA(:,5) = app.RRImported_Data(Rep_StartCell:Rep_FinCell,1);
                        app.Process_DATA(:,6) = app.RRImported_Data(Rep_StartCell:Rep_FinCell,2);

                    case 'IP - Single Site'
                        app.Process_DATA(:,3) = app.Process_DATA(:,1);
                        app.Process_DATA(:,4) = app.Process_DATA(:,2);
                        app.Process_DATA(:,1) = app.MagReplaceImported_Data(MagRep_StartCell:MagRep_FinCell,1);
                        app.Process_DATA(:,2) = app.MagReplaceImported_Data(MagRep_StartCell:MagRep_FinCell,2);

                    case 'IP - with Remote'
                        app.Process_DATA(:,3) = app.Process_DATA(:,1);
                        app.Process_DATA(:,4) = app.Process_DATA(:,2);
                        app.Process_DATA(:,1) = app.MagReplaceImported_Data(MagRep_StartCell:MagRep_FinCell,1);
                        app.Process_DATA(:,2) = app.MagReplaceImported_Data(MagRep_StartCell:MagRep_FinCell,2);
                        app.Process_DATA(:,5) = app.RRImported_Data(Rep_StartCell:Rep_FinCell,1);
                        app.Process_DATA(:,6) = app.RRImported_Data(Rep_StartCell:Rep_FinCell,2);

                end

                Rcount = Rcount + 1;
                Export_Process = 1;

                % Actual processing steps, now that the data has been loaded
                z = z + 1;            % count # files loaded from zero
                y1 = y1 + 1;
                if z == Files2Load
                    z = 0;
                    for j = 1:length(Process_Batch)
                        d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Exporting %g Hz to ASCII',Process_Batch(j)),"Indeterminate",'on');
                        file = strcat('Process_',num2str(Process_Batch(j)),'Hz.txt');

                        if Process_Batch(j) == 1000
                            Export(app,Filter,0,Export_Process,Process_Batch(j),file,d) % export the imported data for processing
                            d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Processing at %g Hz',Process_Batch(j)),"Indeterminate",'on');
                            Lemi_Process(app,1000,f1000num,d)

                            f1000num = f1000num + 1; % update file counter

%                             disp('1000 hz file to delete')
%                             disp(file)
                            delete(file)
                        end
                        if Process_Batch(j) == 500 % if 500 Hz processing option selected...
                            x1 = x1 + 1;
                            Export(app,Filter,1,Export_Process,500,file,d)
                            if x1 == 2
                                d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Processing at %g Hz',Process_Batch(j)),"Indeterminate",'on');
                                Lemi_Process(app,500,f500num,d)
                                delete(file)
                                f500num = f500num + 1;
                                x1 = 0;
                            end
                        end
                        if Process_Batch(j) == 250 % if 250 Hz processing option selected...
                            x2 = x2 + 1;
                            Export(app,Filter,1,Export_Process,250,file,d)
                            if x2 == 4
                                d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Processing at %g Hz',Process_Batch(j)),"Indeterminate",'on');
                                Lemi_Process(app,250,f250num,d)
                                delete(file)
                                f250num = f250num + 1;
                                x2 = 0;
                            end
                        end

                        if Process_Batch(j) == 125 || Process_Batch(j) == 62.5 ||Process_Batch(j) == 31.25 ||Process_Batch(j) == 15.625 ||Process_Batch(j) == 7.8125

                            d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Exporting %g Hz to ASCII',Process_Batch(j)),"Indeterminate",'on');
                            file = sprintf('Process_%.0fHz.txt',Process_Batch(j));

%                             disp('Which freq currently exporting at:')
%                             disp(Process_Batch(j))

                            Export(app,Filter,1,Export_Process,Process_Batch(j),file,d)
                        end

                        app.SSImported_Data = zeros(0, 0);
                        app.SSDate_Time = zeros(0, 0);


                    end
                    if ProcessType == 1 || ProcessType == 3 || ProcessType == 5
%                         disp('currently here')
%                         disp(numel(app.RRImported_Data(:,1)))
                        app.RRImported_Data = app.RRImported_Data(Rep_FinCell:length(app.RRImported_Data),:);
%                         disp(numel(app.RRImported_Data(:,1)))
                        app.RRDate_Time = app.RRDate_Time(Rep_FinCell:length(app.RRDate_Time),:);
                    end

                    if ProcessType == 4 || ProcessType == 5
                        app.MagReplaceImported_Data = app.MagReplaceImported_Data(MagRep_FinCell:length(app.MagReplaceImported_Data),:);
                        app.MagReplaceDate_Time = app.MagReplaceDate_Time(MagRep_FinCell:length(app.MagReplaceDate_Time),:);
                    end
                elseif length(SSfiles) - SScount == 1
                    for j = 1:length(Process_Batch)
                        if Process_Batch(j) == 125 || Process_Batch(j) == 62.5 ||Process_Batch(j) == 31.25 ||Process_Batch(j) == 15.625 ||Process_Batch(j) == 7.8125

                            d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Exporting %g Hz to ASCII',Process_Batch(j)),"Indeterminate",'on');
                            file = sprintf('Process_%.0fHz.txt',Process_Batch(j));

%                             disp('Currently exporting at freq:')
%                             disp(Process_Batch(j))

                            Export(app,Filter,1,Export_Process,Process_Batch(j),file,d)
                        end
                        app.SSImported_Data = zeros(0, 0);
                        app.SSDate_Time = zeros(0, 0);
                    end
                end
            end   % END OF MAIN LOOP

            for j = 1:length(Process_Batch)
                if Process_Batch(j) == 125 || Process_Batch(j) == 62.5 || Process_Batch(j) == 31.25 || Process_Batch(j) == 15.625 || Process_Batch(j) == 7.8125
                    d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Exporting %g Hz to ASCII',Process_Batch(j)),"Indeterminate",'on');
                    file = sprintf('Process_%.0fHz.txt',Process_Batch(j));

                    Export(app,0,1,ProcessType,Process_Batch(j),file,d)
                end
            end
            disp('Currently reseting imported data')
            % reset all imported data
            app.SSImported_Data = zeros(0, 0);
            app.RRImported_Data = zeros(0, 0);
            app.MagReplaceImported_Data = zeros(0, 0);
            app.SSDate_Time = zeros(0, 0);
            app.RRDate_Time = zeros(0, 0);
            app.MagReplaceDate_Time = zeros(0, 0);

            for j = 1:length(Process_Batch)
                if Process_Batch(j) == 125 || Process_Batch(j) == 62.5 || Process_Batch(j) == 31.25 || Process_Batch(j) == 15.625 || Process_Batch(j) == 7.8125
                    try
                        d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Processing at %g Hz',Process_Batch(j)),"Indeterminate",'on');
                        Lemi_Process(app,Process_Batch(j),1,d)
                    catch
                        disp(fprintf('Could not process @ freq %g Hz',Process_Batch(j)))
                    end
                end
            end

            % Delete the previous processing ASCII time series data
            for i = 1:length(Process_Batch)
                try
                    delete(sprintf('Process_%.0fHz.txt',Process_Batch(i)))
                    %                     updateLog(app,append('Cleaning previous processing files: ',string(Process_Batch(i), ' Hz')),1)
                catch
                end
            end

            switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                case 'MT - with Remote'
                    updateLog(app,append('Processing MT site ',SS,' with ',RR,' as Remote Reference is complete'),0)
                case 'MT - Single Site'
                    updateLog(app,append('Processing MT site ',SS,' is complete'),0)
                case 'MT - Remote + Single'
                    updateLog(app,append('Processing MT site ',SS,' with ',RR,' as Single Site + Remote Reference is complete'),0)
                case 'IP - Single Site'
                    updateLog(app,append('Processing IP site ',SS,' is complete'),0)
                case 'IP - with Remote'
                    updateLog(app,append('Processing IP site ',SS,' with ',RR,' as Remote Reference is complete'),0)
            end
            updateLog(app,(''),1)

            % Update the EDI tree according to the sites in the metadata
            t = app.EDI_Tree;
            delete(t.Children)

            for i = 1:numel(app.SurveyData.('SiteName'))
                try    
                    test_node = uitreenode(t,"Text",char(app.SurveyData.('SiteName')(i)),"NodeData",[]);
                
                    x = strcat(app.P_WorkingDirEditField.Value,filesep,char(app.SurveyData.('SiteName')(i)),filesep,'*.edi');
                    d = dir(x);
                    d = d(~contains({d(:).name},{'._'}));
                    d = struct2table(d);
                    d = table2array(d(:,1));
                    d = cellstr(d);

                    for k = 1:numel(d)
                        name = char(d(k));
                        uitreenode(test_node,"Text",name,"NodeData",[char(app.SurveyData.('SiteName')(i))]);
                    end
                catch
                end
            end
        end

        

        function results = SurveyMetaData(app)
            app.SurveyData = readtable(app.SurveyDataEditField.Value);
            app.SurveyDataUITable.Data = app.SurveyData;
            % Change column name from imported data
            app.SurveyDataUITable.ColumnName = app.SurveyData.Properties.VariableNames;
%             app.SurveyDataUITable.ColumnFormat = {[] [] [] [] [] [] [] 'long' 'long' 'short' [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] []  'bank' 'bank' 'bank' 'bank' 'bank' 'bank' 'bank' [] [] [] []};
  
            
            % Populate the Station 2 Process and Remote Refernce drop down lists from survey metadata
            app.StationtoProcessDropDown.Items = app.SurveyData.('SiteName');
            toDelete = app.StationtoProcessDropDown.Value;
            list = app.StationtoProcessDropDown.Items;
            list(strcmp(list, toDelete)) = [];
            app.RemoteReferenceDropDown.Items = list;
            
            UpdateMap(app)

            % Update the Time Series Tree according to the sites in the metadata
            t = app.TimeSeriesTree;
            delete(t.Children)

            for i = 1:numel(app.SurveyData.('SiteName'))
                test_node = uitreenode(t,"Text",char(app.SurveyData.('SiteName')(i)),"NodeData",[]);
                
                x = strcat(app.P_WorkingDirEditField.Value,filesep,char(app.SurveyData.('SiteName')(i)),filesep,'*.B423');
                d = dir(x);
                d = d(~contains({d(:).name},{'._'}));
                d = struct2table(d);
                d = table2array(d(:,1));
                d = cellstr(d);

                for k = 1:numel(d)
                    name = char(d(k));
                    uitreenode(test_node,"Text",name,"NodeData",[char(app.SurveyData.('SiteName')(i))]);
                end
            end

            s1 = uistyle;
            s1.FontWeight = "bold";
            addStyle(t,s1,"level",1)

            % Update the EDI tree according to the sites in the metadata
            t = app.EDI_Tree;
            delete(t.Children)

            for i = 1:numel(app.SurveyData.('SiteName'))
                try    
                    test_node = uitreenode(t,"Text",char(app.SurveyData.('SiteName')(i)),"NodeData",[]);
                
                    x = strcat(app.P_WorkingDirEditField.Value,filesep,char(app.SurveyData.('SiteName')(i)),filesep,'*.edi');
                    d = dir(x);
                    d = d(~contains({d(:).name},{'._'}));
                    d = struct2table(d);
                    d = table2array(d(:,1));
                    d = cellstr(d);

                    for k = 1:numel(d)
                        
                        name = char(d(k));
                        
                        uitreenode(test_node,"Text",name,"NodeData",[char(app.SurveyData.('SiteName')(i))]);
                    end
                catch
                end
            end            
        end
        
        function results = UpdateMap(app)
            d = app.SurveyData.('SiteName');
            d = cell2table(d);
            d(:,2) = {'0 0 0'};
            d(:,3) = {'50'};
            
            idx = contains(d.d,app.StationtoProcessDropDown.Value);
            iwant = find(idx);
            d(iwant,2) = {'0 1 0'};
            d(iwant,3) = {'100'};
            
            if app.Remote_ListBox == 1
                value = app.RemoteReferenceDropDown.Value;
%                 disp('RR value')
%                 disp(value)
                if value
                    idx = contains(d.d,app.RemoteReferenceDropDown.Value);
                    iwant = find(idx);
                    d(iwant,2) = {'0.07 0.62 1.00'};
                    d(iwant,3) = {'100'};
                end
            end

            if app.MagReplace_ListBox == 1
                value = app.MagChannelReplaceDropDown.Value;
                if value
                    idx = contains(d.d,app.MagChannelReplaceDropDown.Value);
                    iwant = find(idx);
                    d(iwant,2) = {'1.00 0.07 0.65'};
                    d(iwant,3) = {'100'};
                end
            end
            
            e = table2array(d);
            e2 = char(e(:,2));
            e2 = str2num(e2);
            e1 = char(e(:,3));
            e1 = str2num(e1);
            
            % Add and Populate Basemap
            delete(app.base_map)
            [app.base_map] = geoaxes(app.Map,"AxisColor",[0.80,0.80,0.80]);
            
            
            s = geoscatter(app.base_map,app.SurveyData.('Latitude'),app.SurveyData.('Longitude'),e1,e2,"filled","v");
            geobasemap(app.base_map,"landcover");
            app.base_map.AxisColor = [0.80,0.80,0.80];
            app.base_map.Scalebar.FontColor = [0 0 0];
            app.base_map.Scalebar.EdgeColor = [0 0 0];
            
            % Add Site name field to data tip when clicked
            row = dataTipTextRow('Site Name',app.SurveyData.('SiteName'));
            s.DataTipTemplate.DataTipRows(end+1) = row;
        end
        
        function results = Dinstance2Remote(app)
            fx = app.SurveyData.('Longitude')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);
            fy = app.SurveyData.('Latitude')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);
            gx = app.SurveyData.('Longitude')(strcmp(app.SurveyData.('SiteName'), app.RemoteReferenceDropDown.Value), :);
            gy = app.SurveyData.('Latitude')(strcmp(app.SurveyData.('SiteName'), app.RemoteReferenceDropDown.Value), :);
            
            c = deg2km(distance(fy, fx, gy, gx));
            
            app.DistancetoRemoteEditField.Value = c;
        end
        
        function results = LoadEDIfile(app,fileName)
%             try
%             fileName = strcat(app.P_WorkingDirEditField.Value,filesep,fileName.NodeData,filesep,fileName.Text);
            disp('file name')
            disp(fileName)
            
            app.EDI_header= textread(fileName,'%s','delimiter','\n');  % load file as cell array by new line
            
            [~,tokens] = regexp(app.EDI_header,'>(\w+(.\w+|)).*//','match','tokens'); % search loaded edi for string matching '>' and ending with '//'
            
            app.EDI_tableNames = convertStringsToChars(replace(strtok(string(vertcat(tokens{:})),' ')','.','_'));
            app.EDI_SearchKEY = strcat('>',strtok(string(vertcat(tokens{:})),' '),' ');
            
            app.EDI_data = [];
            app.EDI_nFreq = [];

%             disp('EDI search key')
%             disp(app.EDI_SearchKEY)

            for i = 1:length(app.EDI_SearchKEY)
                app.EDI_SearchKEY(i,:) = pad(app.EDI_SearchKEY(i,:),strlength(app.EDI_SearchKEY(i,:))+1);
            end
            
            fid =   fopen(fileName,'r');
            %   search fofnar Keywords in file
            gps.elev = 0;
%             iskey = zeros(size(app.EDI_SearchKEY));
            edi_file = fscanf(fid,'%c');
            iskey = regexp(edi_file,app.EDI_SearchKEY);
            
            fseek(fid,0,'bof');
            if ~isempty(iskey{1})
                GPS = 'REFLAT';
                find = regexp(edi_file,'REFLAT');
                while ~contains(fgetl(fid),GPS) 
                    lpos = ftell(fid); 
                end     % search x-identifier
                fseek(fid,lpos,'bof'); 
                line = fgetl(fid);% rewind file pointer by one line
                if contains(line,':')
                    ind = sort([strfind(line,'=')  strfind(line,':')]);
                    deg = abs(str2double(line(ind(1)+1:ind(2)-1)));
                    if contains(line(ind(1)+1:end),'-')
                        signdeg = -1;
                    else
                        signdeg = 1;
                    end
                    minutes = signdeg*str2double(line(ind(2)+1:ind(3)-1));
                    sec = signdeg*str2double(line(ind(3)+1:end));
                    gps.lat = signdeg*deg+minutes/60+sec/3600;
                else
                    ind = strfind(line,'=');
                    gps.lat = str2double(line(ind(1)+1:end));
                end
                fseek(fid,0,'bof');
            end
            
            if ~isempty(iskey{2})
                key = 'REFLON';
                while ~contains(fgetl(fid),key) 
                    lpos = ftell(fid); 
                end     % search x-identifier
                fseek(fid,lpos,'bof');
                line = fgetl(fid);                          % rewind file pointer by one line
                if contains(line,':')
                    ind = sort([strfind(line,'=')  strfind(line,':')]);
                    deg = abs(str2double(line(ind(1)+1:ind(2)-1)));
                    if contains(line(ind(1)+1:end),'-')
                        signdeg = -1;
                    else
                        signdeg = 1;
                    end
                    minutes = signdeg * str2double(line(ind(2) + 1:ind(3)-1));
                    sec = signdeg * str2double(line(ind(3) + 1:end));
                    gps.lon = signdeg * deg + minutes/60 + sec/3600;
                    
                else
                    ind = strfind(line,'=');
                    gps.lon = str2double(line(ind(1)+1:end));
                end
                fseek(fid,0,'bof');
            end
            
            for i = 1:length(app.EDI_SearchKEY)
                key = char(app.EDI_SearchKEY(i,:));
                if ~isempty(iskey{i})
                    %key =   '>FREQ';
                    while ~contains(fgetl(fid),key)
                        lpos = ftell(fid);
                    end     % search x-identifier
                    fseek(fid,lpos,'bof');
                    line = fgetl(fid);                          % rewind file pointer by one line
                    app.EDI_nFreq = str2double(line((strfind(line,'//')+2):end));
                    app.EDI_data(1:app.EDI_nFreq,i) = fscanf(fid,'%f',[1 app.EDI_nFreq])';
                    fseek(fid,0,'bof');
                end
            end
%             app.EDI_Data = array2table(app.EDI_Data,'VariableNames',app.EDI_tableNames);
            fclose(fid);
%             catch
%             end
%             disp('Table names')
%             disp(app.EDI_tableNames)
            % Check if resistivity and phase were in the EDI files
            if ~contains(app.EDI_tableNames,{'RHO'})
                [~, FREQ_pos] = ismember('FREQ',app.EDI_tableNames);
                [~, ZXXR_pos] = ismember('ZXXR',app.EDI_tableNames);
                [~, ZXXI_pos] = ismember('ZXXI',app.EDI_tableNames);
                [~, ZXX_VAR_pos] = ismember('ZXX_VAR',app.EDI_tableNames);
                [~, ZXYR_pos] = ismember('ZXYR',app.EDI_tableNames);
                [~, ZXYI_pos] = ismember('ZXYI',app.EDI_tableNames);
                [~, ZXY_VAR_pos] = ismember('ZXY_VAR',app.EDI_tableNames);
                [~, ZYXR_pos] = ismember('ZYXR',app.EDI_tableNames);
                [~, ZYXI_pos] = ismember('ZYXI',app.EDI_tableNames);
                [~, ZYX_VAR_pos] = ismember('ZYX_VAR',app.EDI_tableNames);
                [~, ZYYR_pos] = ismember('ZYYR',app.EDI_tableNames);
                [~, ZYYI_pos] = ismember('ZYYI',app.EDI_tableNames);
                [~, ZYY_VAR_pos] = ismember('ZYY_VAR',app.EDI_tableNames);

                cN = numel(app.EDI_tableNames);
%                 disp("column number")
%                 disp(cN)
%                 disp('ZXYR position')
%                 disp(ZXYR_pos)

                app.EDI_SearchKEY = [app.EDI_SearchKEY; '>RHOXY'; '>RHOXY_ERR'; '>RHOYX'; '>RHOYX_ERR'; '>RHOXX'; '>RHOXX_ERR'; '>RHOYY'; '>RHOYY_ERR'];
                app.EDI_tableNames = [app.EDI_tableNames 'RHOXY' 'RHOXY_ERR' 'RHOYX' 'RHOYX_ERR' 'RHOXX' 'RHOXX_ERR' 'RHOYY' 'RHOYY_ERR'];

%                 disp('EDI search key - updated')
%                 disp(app.EDI_SearchKEY)

                % Calculate transfer functions + resistivity errors (in log10)
                app.EDI_data(:,cN+1) = (app.EDI_data(:,ZXYR_pos).^2+app.EDI_data(:,ZXYI_pos).^2)./(5*app.EDI_data(:,FREQ_pos));
                app.EDI_data(:,cN+2) = 0.4343*2*app.EDI_data(:,ZXY_VAR_pos)./sqrt(app.EDI_data(:,ZXYR_pos).^2+app.EDI_data(:,ZXYI_pos).^2);

                app.EDI_data(:,cN+3) = (app.EDI_data(:,ZYXR_pos).^2+app.EDI_data(:,ZYXI_pos).^2)./(5*app.EDI_data(:,FREQ_pos));
                app.EDI_data(:,cN+4) = 0.4343*2*app.EDI_data(:,ZYX_VAR_pos)./sqrt(app.EDI_data(:,ZYXR_pos).^2+app.EDI_data(:,ZYXI_pos).^2);

                app.EDI_data(:,cN+5) = (app.EDI_data(:,ZXXR_pos).^2+app.EDI_data(:,ZXXI_pos).^2)./(5*app.EDI_data(:,FREQ_pos));
                app.EDI_data(:,cN+6) = 0.4343*2*app.EDI_data(:,ZXX_VAR_pos)./sqrt(app.EDI_data(:,ZXXR_pos).^2+app.EDI_data(:,ZXXI_pos).^2);

                app.EDI_data(:,cN+7) = (app.EDI_data(:,ZYYR_pos).^2+app.EDI_data(:,ZYYI_pos).^2)./(5*app.EDI_data(:,FREQ_pos));
                app.EDI_data(:,cN+8) = 0.4343*2*app.EDI_data(:,ZYY_VAR_pos)./sqrt(app.EDI_data(:,ZYYR_pos).^2+app.EDI_data(:,ZYYI_pos).^2);

                app.EDI_SearchKEY = [app.EDI_SearchKEY; '>PHSXY'; '>PHSXY_ERR'; '>PHSYX'; '>PHSYX_ERR'; '>PHSXX'; '>PHSXX_ERR'; '>PHSYY'; '>PHSYY_ERR';];
                app.EDI_tableNames = [app.EDI_tableNames 'PHSXY' 'PHSXY_ERR' 'PHSYX' 'PHSYX_ERR' 'PHSXX' 'PHSXX_ERR' 'PHSYY' 'PHSYY_ERR'];

                rad2deg = 180/pi;

                app.EDI_data(:,cN+9) = rad2deg*atan2(app.EDI_data(:,ZXYI_pos),app.EDI_data(:,ZXYR_pos));
                app.EDI_data(:,cN+10) = rad2deg*app.EDI_data(:,ZXY_VAR_pos)./sqrt(app.EDI_data(:,ZXYR_pos).^2+app.EDI_data(:,ZXYI_pos).^2);

                app.EDI_data(:,cN+11) = rad2deg*atan2(app.EDI_data(:,ZYXI_pos),app.EDI_data(:,ZYXR_pos));
                app.EDI_data(:,cN+12) = rad2deg*app.EDI_data(:,ZYX_VAR_pos)./sqrt(app.EDI_data(:,ZYXR_pos).^2+app.EDI_data(:,ZYXI_pos).^2);

                app.EDI_data(:,cN+13) = rad2deg*atan2(app.EDI_data(:,ZXXI_pos),app.EDI_data(:,ZXXR_pos));
                app.EDI_data(:,cN+14) = rad2deg*app.EDI_data(:,ZXX_VAR_pos)./sqrt(app.EDI_data(:,ZXXR_pos).^2+app.EDI_data(:,ZXXI_pos).^2);

                app.EDI_data(:,cN+15) = rad2deg*atan2(app.EDI_data(:,ZYYI_pos),app.EDI_data(:,ZYYR_pos));
                app.EDI_data(:,cN+16) = rad2deg*app.EDI_data(:,ZYY_VAR_pos)./sqrt(app.EDI_data(:,ZYYR_pos).^2+app.EDI_data(:,ZYYI_pos).^2);
            else
%                 disp('Yes Rho is there')
            end
        end

        function results = WriteEDIfile(app,fileName1)
            rot = 0.0;

            meanFreq = [2.0000E+02 1.5625E+02 1.2500E+02 1.0000E+02 8.0005E+01 6.2896E+01 5.0000E+01 3.9896E+01 3.1514E+01 2.5031E+01 1.9985E+01 1.5784E+01 1.2538E+01 1.0013E+01 7.9792E+00 6.2974E+00 5.0029E+00 3.9883E+00 3.1521E+00 2.5050E+00 1.9961E+00 1.5774E+00 1.2547E+00 1.0010E+00 7.9717E-01 6.3033E-01 5.0050E-01 3.9858E-01 3.1537E-01 2.5060E-01 1.9951E-01 1.5774E-01 1.2547E-01 1.0010E-01 7.9717E-02 6.3033E-02 5.0050E-02 3.9858E-02 3.1537E-02 2.5060E-02 1.9951E-02 1.5774E-02 1.2547E-02 1.0010E-02 7.9717E-03 6.3033E-03 5.0057E-03 3.9883E-03 3.1521E-03 2.5059E-03 1.9947E-03 1.5791E-03 1.2553E-03 1.0016E-03 7.9621E-04 6.3069E-04 5.0067E-04 3.9896E-04 3.1514E-04 2.5063E-04 1.9881E-04 1.5724E-04];
            [~,minEDIfreq] = min(abs(meanFreq-app.EDI_data(1,1)));
            
            %fname    =   strcat('s',(num2str(s,'%02d')),'.zss');
            fid = fopen(fileName1,'w+');
            
            % write header
            for i = 1:51
                fprintf(fid,'%s\n',app.EDI_header{i,:});
            end
            
            % write frequencies
            fprintf(fid,'>FREQ NFREQ=%d // %d\n ',app.EDI_nFreq,app.EDI_nFreq);
            for p = 0:app.EDI_nFreq-1
                fprintf(fid,' %.5E ',meanFreq(1,minEDIfreq+p));
                app.EDI_data(p+1,1)=meanFreq(1,minEDIfreq+p);
                if mod((p+1),5)==0
                    fprintf(fid,'\n ');
                end
            end
            fprintf(fid,'\n');
            fprintf(fid,'\n');

            % write z rotation
            fprintf(fid,'>ZROT // %d\n ',app.EDI_nFreq);
            for p = 1:app.EDI_nFreq
                fprintf(fid,' %.5E ',app.EDI_data(p,2));
                if mod(p,5)==0
                    fprintf(fid,'\n ');
                end
            end
            fprintf(fid,'\n');
            
%             disp('EDI search key')
%             disp(app.EDI_SearchKEY)
%             disp('EDI Table Names')
%             disp(app.EDI_tableNames)

            % write rest of the EDI data
            for p = 3:length(app.EDI_tableNames)
                fprintf(fid,'\n');
                fprintf(fid,'%s ROT=%.1f // %d\n ',app.EDI_SearchKEY(p),rot,app.EDI_nFreq);
                for j = 1:app.EDI_nFreq
                    fprintf(fid,'%.5e ',app.EDI_data(j,p));
                    if mod(j,5)==0
                        fprintf(fid,'\n ');
                    end
                end
%                 if p==3 || p==4 || p==5 || p==9 || p==10 || p==11
%                     for j = 1:app.EDI_nFreq
%                         if app.EDI_data(j,p+3)<0
%                             fprintf(fid,'%.5E ',app.EDI_data(j,p+3)*sqrt(app.EDI_data(j,1)));
%                         else
%                             fprintf(fid,' %.5E ',app.EDI_data(j,p+3)*sqrt(app.EDI_data(j,1)));
%                         end
%                         if mod(j,5)==0
%                             fprintf(fid,'\n ');
%                         end                    
%                     end
%                 end
%                 if p==6 || p==7 || p==8 || p==12 || p==13 || p==14
%                     for j = 1:app.EDI_nFreq
%                         if app.EDI_data(j,p-3)<0
%                             fprintf(fid,'%.5E ',app.EDI_data(j,p-3)*sqrt(app.EDI_data(j,1)));
%                         else
%                             fprintf(fid,' %.5E ',app.EDI_data(j,p-3)*sqrt(app.EDI_data(j,1)));
%                         end
%                         if mod(j,5)==0
%                             fprintf(fid,'\n ');
%                         end                    
%                     end
%                 end
                fprintf(fid,'\n');
            end
            fprintf(fid,'\n');
            
            fprintf(fid,'>END\n');
            fclose(fid);
        end
        
        function results = PopulateNotch(app)
            value = app.HertzCheckBox.Value;
            if value
                width = app.WidthSlider.Value;
                newRow = {50-round(width,1),50+round(width,1)};
                value = app.HarmonicsCheckBox.Value;
                if value
                    newHar1 = {100-round(width,1),100+round(width,1);};
                    newHar2 = {150-round(width,1),150+round(width,1);};
                    newHar3 = {200-round(width,1),200+round(width,1);};
                    newHar4 = {250-round(width,1),250+round(width,1);};
                    newHar5 = {300-round(width,1),300+round(width,1);};
                    newHar6 = {350-round(width,1),350+round(width,1);};
                    newHar7 = {400-round(width,1),400+round(width,1);};
                    newHar8 = {450-round(width,1),450+round(width,1);};
                    newData = [newRow; newHar1; newHar2; newHar3; newHar4; newHar5; newHar6; newHar7; newHar8];
                else
                    newData = newRow;
                end
                app.UITable.Data = newData;
                app.UITable.ColumnFormat = {'bank' 'bank'};
            end
            
            value = app.HzFilterCheckBox.Value;
            if value
                width = 1;
                newRow = {50-round(width,1),50+round(width,1)};
                value = app.HarmonicsCheckBox_2.Value;
                if value
                    newHar1 = {100-round(width,1),100+round(width,1);};
                    newHar2 = {150-round(width,1),150+round(width,1);};
                    newHar3 = {200-round(width,1),200+round(width,1);};
                    newHar4 = {250-round(width,1),250+round(width,1);};
                    newHar5 = {300-round(width,1),300+round(width,1);};
                    newHar6 = {350-round(width,1),350+round(width,1);};
                    newHar7 = {400-round(width,1),400+round(width,1);};
                    newHar8 = {450-round(width,1),450+round(width,1);};
                    newData = [newRow; newHar1; newHar2; newHar3; newHar4; newHar5; newHar6; newHar7; newHar8];
                else
                    newData = newRow;
                end
                app.UITable.Enable = 'on';
                app.UITable.Data = newData;
            end
        end
        
        function results = WorkingFiles(app,WorkDir)
            app.P_WorkingDirEditField.Value = WorkDir;
%             app.ExportDataDirEditField.Value = WorkDir;
             try

                d = dir(strcat(app.P_WorkingDirEditField.Value,filesep,'*.B423'));

                d = struct2table(d);
                d = table2array(d(:,1));
                d = cellstr(d);
                app.SelectedFilesListBox.Items = (d);
                app.EportDataFilesListBox.Items = (d);

                disp('this worked')
            catch
                d = ('');
                d = cellstr(d);
%                 app.SelectedFilesListBox.Items = d;
                disp('this did not work')
            end
        end
        
        function results = SurveySyncTime(app)
            Bstart = app.SurveyData.('StartTime')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);
            Bstart = datetime(Bstart,'InputFormat','yyyy/MM/dd HH:mm:ss');
            Rstart = app.SurveyData.('StartTime')(strcmp(app.SurveyData.('SiteName'), app.RemoteReferenceDropDown.Value), :);
            Rstart = datetime(Rstart,'InputFormat','yyyy/MM/dd HH:mm:ss');
            
            Bend = app.SurveyData.('FinishTime')(strcmp(app.SurveyData.('SiteName'), app.StationtoProcessDropDown.Value), :);
            Bend = datetime(Bend,'InputFormat','yyyy/MM/dd HH:mm:ss');
            Rend = app.SurveyData.('FinishTime')(strcmp(app.SurveyData.('SiteName'), app.RemoteReferenceDropDown.Value), :);
            Rend = datetime(Rend,'InputFormat','yyyy/MM/dd HH:mm:ss');
            
            if Bstart < Rstart
                if Bend < Rend
                    x = (Bend - Rstart);
                else
                    x = (Rend - Rstart);
                end
            else
                if Bend < Rend
                    x = (Bend - Bstart);
                else
                    x = (Rend - Bstart);
                end
            end
            app.TimesyncEditField.Value = hours(x);
        end
        
        
        function results = SlideBar(app,StartTime,EndTime)
            % Create the 1D axes
            delete(app.l)
            delete(app.ax)
            app.StartSpinner.Value = StartTime;
            app.FinishSpinner.Value = EndTime;
            
            app.ax = axes(app.Panel_Slider,'Color','none','YColor','none','XLim',[StartTime,EndTime], ...
                'YTick',[],'XTick',[],'TickDir','both','TickLength',[0.5,0.035], ...
                'XMinorTick','off','Units','pixels','Position',[10,20,540,0] ...
                );
            
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.ax);
            app.ax.Toolbar.Visible = 'off';
            % Add the line ROI
            app.l = images.roi.Line(app.ax,'Position',[StartTime,0;EndTime,0],"Color",[0.07,0.62,1.00],"LineWidth",4);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.l,'ROIMoved',@(varargin)lMoving(app,app.l));
        end
        
        function results = SelectData_Slide(app)
            
            %disp('File List')
            %disp(app.SSFile_List)

            app.StartEditField.Value = char(datetime(double(app.StartSpinner.Value),'ConvertFrom','posixtime'));
            app.FinishEditField.Value = char(datetime(double(app.FinishSpinner.Value),'ConvertFrom','posixtime'));
            
            try1 = str2double(strtok(string(app.SSFile_List),'.'));
            %disp('remove b423')
            %disp(try1)
            
            [row,~] = find(try1>app.StartSpinner.Value & try1<app.FinishSpinner.Value);
            %disp('Row numbers')
            %disp(row)
            row = sort(cat(1,row,min(row)-1,max(row)+1));
            app.SingleSiteList.Value = row;
            
%             disp('start spinner value')
%             disp(app.StartSpinner.Value)
%             disp(class(app.StartSpinner.Value))
             
            rowMax = max(row);
            rowMin = min(row);
            rowMaxN = str2double(strtok(string(app.SingleSiteList.Items(rowMax)),'.'));
            rowMinN = str2double(strtok(string(app.SingleSiteList.Items(rowMin)),'.'));

            %             disp('SSrowMin'); disp(rowMin); disp(rowMinN); disp(class(rowMinN))
            %             disp('SSrowMax'); disp(rowMax); disp(rowMaxN); disp(class(rowMaxN))
            try
                try2 = str2double(strtok(string(app.RRFile_List),'.'));
                %             disp('RR file list')
                %             disp(try2(12:15,:))
                %             disp(class(try2))
                %[row,~] = find(try1>app.StartSpinner.Value & try1<app.FinishSpinner.Value);
                [row,~] = find(try2>rowMinN & try2<rowMaxN);
                row = sort(cat(1,row,min(row)-1,max(row)+1));

                %             disp('RRrow')
                %             disp(row)

                app.RemoteSiteList.Value = row;
            end
            %             rowMax = max(row);
            %             rowMin = min(row);

            %             disp('RRrowMin'); disp(rowMin); disp(app.RemoteSiteList.Items(rowMin))
            %             disp('RRrowMax'); disp(rowMax); disp(app.RemoteSiteList.Items(rowMax))

            %app.StartSpinner.Value = app.l.Position(1,1);
            %app.FinishSpinner.Value = app.l.Position(2,1);
            %             disp('RR list first value')
            %             disp(app.RemoteSiteList.Items(row(1)))
            try
                try3 = str2double(strtok(string(app.MagReplaceFile_List),'.'));
                [row,~] = find(try3>rowMinN & try3<rowMaxN);
                row = sort(cat(1,row,min(row)-1,max(row)+1));
                app.MagChannelReplaceList.Value = row;

            end
        end


        function results = EDI_Viewer(app)
%             fileName = app.EDI_Tree.SelectedNodes;
% 
%             LoadEDIfile(app,fileName)

            % Select the phase angle from the selection bar
            switch app.Phase_ButtonGroup.SelectedObject.Text
                case '(0,90)'
                    YX_phase = 180;
                    phase_YscaleMin = 0;
                    phase_YscaleMax= 90;
                    phase_Ytick = [0, 45, 90];
                    phase_Ytick_label = {0, 45, 90};

                case '(-180,180)'
                    YX_phase = 0;
                    phase_YscaleMin = -180;
                    phase_YscaleMax = 180;
                    phase_Ytick = [-180, -90, 0, 90, 180];
                    phase_Ytick_label = {-180, -90, 0, 90, 180};

                case '(-360,360)'
                    YX_phase = 0;
                    phase_YscaleMin = -360;
                    phase_YscaleMax = 360;
                    phase_Ytick = [-360, -180, 0, 180, 360];
                    phase_Ytick_label = {-360, -180, 0, 180, 360};
            end
            try

            legend_val = [];

            % Find the column number of XX, XY, YX, YY in the table names
            tablenames = string(app.EDI_tableNames);
            [~, rhoXX_pos] = ismember('RHOXX',tablenames);
            [~, rhoXY_pos] = ismember('RHOXY',tablenames);
            [~, rhoYX_pos] = ismember('RHOYX',tablenames);
            [~, rhoYY_pos] = ismember('RHOYY',tablenames);
            [~, phsXX_pos] = ismember('PHSXX',tablenames);
            [~, phsXY_pos] = ismember('PHSXY',tablenames);
            [~, phsYX_pos] = ismember('PHSYX',tablenames);
            [~, phsYY_pos] = ismember('PHSYY',tablenames);

            delete(app.EDI_view_box)
            [app.EDI_view_box] = tiledlayout(app.EDI_display,3,1,'TileSpacing','compact');

            ax1 = nexttile(app.EDI_view_box,[2 1]); % tile plotting
            ax1.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            ax1.Toolbar = [];

            if app.EDI_XXCheckBox.Value == 1
                errorbar(ax1,1./app.EDI_data(:,1),app.EDI_data(:,rhoXX_pos),app.EDI_data(:,rhoXX_pos+1),"-o", ...
                    "MarkerSize",5, ...
                    "MarkerEdgeColor","green", ...
                    "MarkerFaceColor",[0 1 0]);
                hold(ax1,"on")
                legend_val = [legend_val; 'xx'];
            end
            if app.EDI_YYCheckBox.Value == 1
                errorbar(ax1,1./app.EDI_data(:,1),app.EDI_data(:,rhoYY_pos),app.EDI_data(:,rhoYY_pos+1),"-o", ...
                    "MarkerSize",5, ...
                    "MarkerEdgeColor","yellow", ...
                    "MarkerFaceColor",[1 1 0]);
                hold(ax1,"on")
                legend_val = [legend_val; 'yy'];
            end
            if app.EDI_YXCheckBox.Value == 1
                errorbar(ax1,1./app.EDI_data(:,1),app.EDI_data(:,rhoYX_pos),app.EDI_data(:,rhoYX_pos+1),"-s", ...
                    "MarkerSize",5, ...
                    "MarkerEdgeColor","blue", ...
                    "MarkerFaceColor",[0.65 0.85 0.90]);
                hold(ax1,"on")
                legend_val = [legend_val; 'yx'];
            end
            if app.EDI_XYCheckBox.Value == 1
                errorbar(ax1,1./app.EDI_data(:,1),app.EDI_data(:,rhoXY_pos),app.EDI_data(:,rhoXY_pos+1),"-o", ...
                    "MarkerSize",5, ...
                    "MarkerEdgeColor","red", ...
                    "MarkerFaceColor",[1 0 0]);
                hold(ax1,"on")
                legend_val = [legend_val; 'xy'];
            end

            set(ax1,'XScale','log', ...
                'YScale','log', ...
                'XTickLabel',[], ...
                'GridColor',[0.1,0.1,0.1], ...
                'MinorGridColor',[0.1,0.1,0.1], ...
                'XColor',[0.94,0.94,0.94], ...
                'YColor',[0.94,0.94,0.94], ...
                'TickDir','out')
            ax1.YLabel.String = 'App Resistivity (ohm.m)';
            ax1.FontSize = 13;
            grid(ax1,'on')
            grid(ax1,'minor')
            ax1.MinorGridAlpha = 0.1;
            legend(ax1,legend_val,'Location','northwest')
            hold(ax1,"off")

            ax2 = nexttile(app.EDI_view_box);
            if app.EDI_YXCheckBox.Value == 1
                errorbar(ax2,1./app.EDI_data(:,1),app.EDI_data(:,phsYX_pos)+YX_phase,app.EDI_data(:,phsYX_pos+1),"-s", ...
                    "MarkerSize",5, ...
                    "MarkerEdgeColor","blue", ...
                    "MarkerFaceColor",[0.65 0.85 0.90]);
                hold(ax2,"on")
            end
            if app.EDI_XYCheckBox.Value == 1
                errorbar(ax2,1./app.EDI_data(:,1),app.EDI_data(:,phsXY_pos),app.EDI_data(:,phsXY_pos+1),"-o", ...
                    "MarkerSize",5, ...
                    "MarkerEdgeColor","red", ...
                    "MarkerFaceColor",[1 0 0]);
                hold(ax2,"on")
            end
            if app.EDI_XXCheckBox.Value == 1
                errorbar(ax2,1./app.EDI_data(:,1),app.EDI_data(:,phsXX_pos),app.EDI_data(:,phsXX_pos+1),"-s", ...
                    "MarkerSize",5, ...
                    "MarkerEdgeColor","green", ...
                    "MarkerFaceColor",[0 1 0]);
                hold(ax2,"on")
            end
            if app.EDI_YYCheckBox.Value == 1
                errorbar(ax2,1./app.EDI_data(:,1),app.EDI_data(:,phsYY_pos),app.EDI_data(:,phsYY_pos+1),"-o", ...
                    "MarkerSize",5, ...
                    "MarkerEdgeColor","yellow", ...
                    "MarkerFaceColor",[1 1 0]);
            end

            set(ax2, 'XScale','log', ...
                'YScale','linear', ...
                'YTick',(phase_Ytick), ...
                'YTickLabel',phase_Ytick_label, ...
                'GridColor',[0.1,0.1,0.1], ...
                'MinorGridColor',[0.1,0.1,0.1], ...
                'XColor',[0.94,0.94,0.94], ...
                'YColor',[0.94,0.94,0.94], ...
                'TickDir','both')
            ax2.YLabel.String = 'Phase (deg)';
            ax2.XLabel.String = 'Period (s)';
            ax2.FontSize = 13;
            grid(ax2,'on')
            grid(ax2,'minor')
            ax2.MinorGridAlpha = 0.1;
            hold(ax2,"off")

            axis(ax1,[app.XAxis_MinEditField.Value app.XAxis_MaxEditField.Value app.YAxis_MinEditField.Value app.YAxis_MaxEditField.Value])
            axis(ax2,[app.XAxis_MinEditField.Value app.XAxis_MaxEditField.Value phase_YscaleMin phase_YscaleMax])
            linkaxes([ax1,ax2],'x'); % link selected x axis together
            catch
            end

        end
        
        function results = updateMetaData(app)
            d = uiprogressdlg(app.MT_Field_App_2022b,'Title','Adding new site',"Indeterminate",'on');

            % get site name from selected folder name
            SiteName = uigetdir;
            SiteName = split(SiteName,filesep);
            header = struct('SiteName',string(SiteName(numel(SiteName))));

            % get the first + last binary file names
            binaryFiles = dir(strcat(string(SiteName(numel(SiteName))),filesep,'*.B423'));
            binaryFiles = binaryFiles(~contains({binaryFiles(:).name},{'._'}));     
            FirstBinaryFile = binaryFiles(1);
            LastBinaryFile = binaryFiles(numel(binaryFiles));

            % read the first binary metadata
            File2Load = strcat(FirstBinaryFile.folder,filesep,FirstBinaryFile.name);
            fID = fopen(char(File2Load));                       % open file to import

            line = fgetl(fID);        % Start at first line of file

            header.box_number = str2double(line(end-3:end));
            for i = 1:10                                    % loop through first 28 lines of the binary header
                line = fgetl(fID);                          % get line number
                if ~contains(line,'%')                      % if line contains = then continue
                    continue
                end

                tokens = split(strrep(line,'%',''),' ');  %
                header.(tokens{1}) = (tokens{2}); %
            end

            line = fgetl(fID);
            alt = split(strrep(line,'%',''),',');
%             alt = split(alt{1},'  ');
            header.Altitude = extractBetween(alt(1),5,numel(alt{1}));

%             fclose(char(File2Load));

            % Get extra Metadata
            prompt = {'Project Name:', ...
                'Location Area:', ...
                'Aquired By:', ...
                'Aquired By Organisation:', ...
                'Datum:', ...
                'Orientation Reference Frame (i.e. magnetic/geographic):', ...
                'Magnettic Coil Type (i.e. Lemi-120, MTU-150:', ...
                'Electrode Type (i.e. Borin Stelth Ag-AgCl, Lemi-701 CuS04):', ...
                'Time Zone (i.e. Africa/Casablanca'};
            dlgtitle = 'New Site Inputs';
            dims = [1 90];
            definput = {'Morocco','Atlas','Goran Boren','University of Adelaide','WGS84','Magnetic','Lemi-120','Borin Stelth Ag-AgCl','Africa/Casablanca'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            header.ProjectName = answer{1};
            header.LocationArea = answer{2};
            header.AquiredBy = answer{3};
            header.AquiredByOrg = answer{4};
            header.Datum = answer{5};
            header.RefererenceFrame = answer{6};
            header.MagCoilType = answer{7};
            header.ElectrodeType = answer{8};
            header.TimeZone = answer{9};

            % Get dipole distances
            prompt = {'Ex Dipole (m):', ...
                'Ey Dipole (m):', ...
                'Ex Orientation (degrees):', ...
                'Ey Orientation (degrees):', ...
                'Bx coil #', ...
                'By coil #', ...
                'Bz coil #', ...
                'Bx Orientation (degrees):', ...
                'By Orientation (degrees):'};
            dlgtitle = 'New Site Inputs';
            dims = [1 50];
            definput = {'50','50','0','90','-','-','-','0','90'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            header.ExDipole = answer{1};
            header.EyDipole = answer{2};
            header.ExAzimuth = answer{3};
            header.EyAzimuth = answer{4};
            header.BxCoil = answer{5};
            header.ByCoil = answer{6};
            header.BzCoil = answer{7};
            header.BxAzimuth = answer{8};
            header.ByAzimuth = answer{9};

            % Get extra metadata
            prompt = {'Battery #', ...
                'Battery Voltage (V):', ...
                'Resistance - North - Ground (k.Ohm):', ...
                'Resistance - North - South (k.Ohm):', ...
                'Resistance - East - West (k.Ohm):', ...
                'Voltage - North - Ground (mV):', ...
                'Voltage - North - South (mV):', ...
                'Voltage - East - West (mV):', ...
                'Deployment Notes:', ...
                'Pickup Notes:'};
            dlgtitle = 'New Site Inputs';
            dims = [1 90];
            definput = {'','','','','','','','','',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            header.BatteryNumber = answer{1};
            header.BatteryVoltage = answer{2};
            header.ResistanceNG = answer{3};
            header.ResistanceNS = answer{4};
            header.ResistanceEW = answer{5};
            header.VoltageNG = answer{6};
            header.VoltageNS = answer{7};
            header.VoltageEW = answer{8};
            header.NotesDeployment = answer{9};
            header.NotesPickup = answer{10};

            % correct the lat + long values
            lat = split(header.Lat,',');
            lat(3) = extractBetween(lat{1},1,2);  % extracts the first two values from the lat **might need changing if its close to the equator (i.e. 1 value instead of two)**
            lat(4) = extractBetween(lat{1},3,length(lat{1}));
            lat{3} = str2double(lat{3});
            lat{4} = str2double(lat{4});
            if lat{2} == 'S'
                lat{3} = -lat{3};
            end

            long = split(header.Lon,',');
            long(3) = extractBetween(long{1},1,3);  % extracts the first three values from the long **might need changing if its close to the greenwitch meantime (i.e. 1 or 2 values instead of three)**
            long(4) = extractBetween(long{1},4,length(long{1}));
            long{3} = str2double(long{3});
            long{4} = str2double(long{4});
            if long{2} == 'W'
                long{3} = -long{3};
            end

            % update the lat and long values in the header
            header.Lat = dm2degrees([lat{3} lat{4}]);
            header.Lon = dm2degrees([long{3} long{4}]);

            % get the start time from the first header
            StartTime = join([header.Date,' ',header.Time]);
            StartTime = datetime(StartTime,'InputFormat','yyyy/MM/dd HH:mm:ss','Format','yyyy/MM/dd HH:mm:ss','TimeZone',header.TimeZone);

            % get the finish time from the last binary file
            File2Load = strcat(FirstBinaryFile.folder,filesep,LastBinaryFile.name);
            fID = fopen(char(File2Load));                       % open file to import

            d.Message = 'Finding finish time';

            % Load Binary Time Data
            fseek(fID,1024,'bof');
            t = fread(fID,Inf,'ulong',26);
            fseek(fID,1028,'bof');
            FracSec = fread(fID,Inf,'uint16',28);
            app.SampleRate = max(FracSec)+1;
            app.DateTime = datetime(t,'ConvertFrom','posixTime','Format','yyyy mm dd HH MM SS');
            [Y,M,D,H,MN,S] = datevec(app.DateTime);
            FracSec = S+(FracSec/app.SampleRate);
            app.DateNumber = datenum(Y,M,D,H,MN,FracSec);
            app.DateTime = datetime(Y,M,D,H,MN,FracSec);

            disp('Time')
            disp(app.DateTime(numel(app.DateTime)))
 
%             fseek(fID,1024,'bof');
%             t = fread(fID,Inf,'ulong',26);    % yyyy/mm/dd HH:MM:SS'
            FinishTime = datetime(app.DateTime(numel(app.DateTime)),'Format','yyyy/MM/dd HH:mm:ss','TimeZone',header.TimeZone);

            % calculate the deployment length
            DeploymentLength = FinishTime - StartTime;

            % write new data to the csv file
            newDataLine = table(header.SiteName, ...
                {header.ProjectName}, ...
                {header.LocationArea}, ...
                {header.AquiredBy}, ...
                {header.AquiredByOrg}, ...
                {header.Datum}, ...
                {header.RefererenceFrame}, ...
                header.Lat, ...
                header.Lon, ...
                {header.Altitude}, ...
                {header.TimeZone}, ...
                StartTime, ...
                FinishTime, ...
                DeploymentLength, ...
                app.SampleRate, ...
                header.box_number, ...
                {header.ElectrodeType}, ...
                {header.ExDipole}, ...
                {header.ExAzimuth}, ...
                {header.EyDipole}, ...
                {header.EyAzimuth}, ...
                {header.MagCoilType}, ...
                {header.BxAzimuth}, ...
                {header.ByAzimuth}, ...
                {header.BxCoil}, ...
                {header.ByCoil}, ...
                {header.BzCoil}, ...
                {header.BatteryNumber}, ...
                {header.BatteryVoltage}, ...
                {header.ResistanceNG}, ...
                {header.ResistanceNS}, ...
                {header.ResistanceEW}, ...
                {header.VoltageNG}, ...
                {header.VoltageNS}, ...
                {header.VoltageEW}, ...
                {header.NotesDeployment}, ...
                {header.NotesPickup}, ...
                {'BINARY'}, ...
                0);
            writetable(newDataLine,app.SurveyDataEditField.Value,'WriteMode','Append','WriteVariableNames',false,'WriteRowNames',true)  
            
            fclose('all');

            % update table
            SurveyMetaData(app)

            close(d)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start up fuction when app is loaded used to populate initial values/properties
            app.P_WorkingDirEditField.Value = pwd;  % set current working dir to pwd
            app.P_LEMIDirEditField_2.Value = pwd;
            
            WorkingFiles(app,app.P_WorkingDirEditField.Value)
            app.BatchNumbers = 1;
            
            try
                t = dir('*.csv');
                t = t(~contains({t(:).name},{'._'}));
                t = fullfile(t.folder,t.name);
                
                app.SurveyDataEditField.Value = t;
                SurveyMetaData(app)
                app.Remote_ListBox = 1;
                ProcessSiteList(app)
            catch err
                
            end
            
            updateLog(app,('    _______ _______    _______ __         __     __                         ______ ______ ______ ______ '),1)
            updateLog(app,('   |   |   |_     _|  |    ___|__|.-----.|  |.--|  |  .---.-.-----.-----.  |__    |      |__    |__    |'),1)
            updateLog(app,('<==|       |=|   |====|    ___|  ||  -__||  ||  _  |==|  _  |  _  |  _  |==|    __|  --  |    __|    __|==>'),1)
            updateLog(app,(' <=|__|_|__|=|___|====|___|===|__||_____||__||_____|==|___._|   __|   __|==|______|______|______|______|=>'),1)
            updateLog(app,('  <=========================================================|__|==|__|==================================>'),1)
            updateLog(app,('   <====b====y====b====k====u====o====a====g====e====o====p====h====y====s====g====r====o====u====p====>'),1)
            updateLog(app,('    <=================================================================================================> '),1)
            updateLog(app,(''),1)
            
            disp('    _______ _______    _______ __         __     __                         ______ ______ ______ ______ ')
            disp('   |   |   |_     _|  |    ___|__|.-----.|  |.--|  |  .---.-.-----.-----.  |__    |      |__    |__    |')
            disp('<==|       |=|   |====|    ___|  ||  -__||  ||  _  |==|  _  |  _  |  _  |==|    __|  --  |    __|    __|==>')
            disp(' <=|__|_|__|=|___|====|___|===|__||_____||__||_____|==|___._|   __|   __|==|______|______|______|______|=>')
            disp('  <=========================================================|__|==|__|==================================> ')
            disp('   <====b====y====b====k====u====o====a====g====e====o====p====h====y====s====g====r====o====u====p====>')
            disp('    <=================================================================================================> ')
            disp(' ')
            
            SlideBar(app,1649039406,1649050206)
            ProcessingOptionButtonGroupSelectionChanged(app)
            SelectData_Slide(app)
        end

        % Button pushed function: ButtonAdd
        function ButtonAddPushed(app, event)
            % adding new row to notch filter table
            oldData = app.UITable.Data;
            newRow = {1,1};
            newData = [oldData; newRow];
            app.UITable.Data = newData;
        end

        % Button pushed function: RunFiltersButton
        function RunFiltersButtonPushed(app, event)
            FilterData(app)
            PlotFilter(app)
        end

        % Button pushed function: ButtonDel
        function ButtonDelPushed(app, event)
            % delete last added row in notch filter table
            data = app.UITable.Data;
            lastn = 1;
            data = data(1:end-lastn,:);
            app.UITable.Data = data;
        end

        % Button pushed function: SelectSurveyDataButton
        function SelectSurveyDataButtonPushed(app, event)
            [S_filename, pathname] = uigetfile({'*.csv'},'File Selector');
            fullpath = strcat(pathname,S_filename);
            app.SurveyDataEditField.Value = fullpath;
            SurveyMetaData(app)
        end

        % Value changed function: StationtoProcessDropDown
        function StationtoProcessDropDownValueChanged(app, event)
            toDelete = app.StationtoProcessDropDown.Value;
            list = app.StationtoProcessDropDown.Items;
            list(strcmp(list, toDelete)) = [];
            app.RemoteReferenceDropDown.Items = list;
            
            Dinstance2Remote(app)
            SurveySyncTime(app)
            ProcessSiteList(app)
            
            toDeleteRR = app.RemoteReferenceDropDown.Value;
            list(strcmp(list, toDeleteRR)) = [];
            app.MagChannelReplaceDropDown.Items = list;
            
            UpdateMap(app)
        end

        % Value changed function: RemoteReferenceDropDown
        function RemoteReferenceDropDownValueChanged(app, event)
            Dinstance2Remote(app)
            SurveySyncTime(app)
            ProcessSiteList(app)
            
            list = app.StationtoProcessDropDown.Items;
            toDeleteRR = app.RemoteReferenceDropDown.Value;
            list(strcmp(list, toDeleteRR)) = [];
            app.MagChannelReplaceDropDown.Items = list;
        end

        % Button pushed function: P_ChangeWorkingDirButton
        function P_ChangeWorkingDirButtonPushed(app, event)
            selpath = uigetdir(pwd,'Select working dir');
            app.P_WorkingDirEditField.Value = selpath;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            ProcessData(app)
        end

        % Value changed function: HertzCheckBox
        function HertzCheckBoxValueChanged(app, event)
            PopulateNotch(app)
        end

        % Value changed function: WidthSlider
        function WidthSliderValueChanged(app, event)
            PopulateNotch(app)
        end

        % Value changed function: HarmonicsCheckBox
        function HarmonicsCheckBoxValueChanged(app, event)
            PopulateNotch(app)
        end

        % Selection changed function: ProcessingOptionButtonGroup
        function ProcessingOptionButtonGroupSelectionChanged(app, event)
            %             selectedButton = app.ProcessingOptionButtonGroup.SelectedObject.Text;
            %             disp(selectedButton)
            toDelete = app.StationtoProcessDropDown.Value;
            list = app.StationtoProcessDropDown.Items;
            list(strcmp(list, toDelete)) = [];

            switch app.ProcessingOptionButtonGroup.SelectedObject.Text
                case 'MT - with Remote'
                    app.RemoteSiteList.Enable = "on";
                    app.RemoteReferenceDropDown.Enable = "on";
                    app.RemoteReferenceDropDownLabel.Enable = "on";
                    app.Remote_ListBox = 1;

                    app.MagChannelReplaceList.Enable = "off";
                    app.MagChannelReplaceDropDown.Enable = "off";
                    app.MagChannelReplaceDropDownLabel.Enable = "off";
                    app.MagReplace_ListBox = 0;

                    app.ReplaceMagChannelCheckBox.Enable = "on";
                    app.ReplaceMagChannelCheckBox.Value = 0;

                    app.StationtoProcessDropDownLabel.Text = 'Station to Process';
                    app.MagChannelReplaceDropDownLabel.Text = 'Mag Channel Replace';

                    app.RemoteReferenceDropDown.Items = list;
                    app.MagChannelReplaceDropDown.Items = " ";

                case 'MT - Single Site'
                    app.RemoteSiteList.Enable = "off";
                    app.RemoteReferenceDropDown.Enable = "off";
                    app.RemoteReferenceDropDownLabel.Enable = "off";
                    app.Remote_ListBox = 0;

                    app.MagChannelReplaceList.Enable = "off";
                    app.MagChannelReplaceDropDown.Enable = "off";
                    app.MagChannelReplaceDropDownLabel.Enable = "off";
                    app.MagReplace_ListBox = 0;

                    app.ReplaceMagChannelCheckBox.Enable = "on";
                    app.ReplaceMagChannelCheckBox.Value = 0;

                    app.StationtoProcessDropDownLabel.Text = 'Station to Process';
                    app.MagChannelReplaceDropDownLabel.Text = 'Mag Channel Replace';

                    app.RemoteReferenceDropDown.Items = " ";
                    app.MagChannelReplaceDropDown.Items = " ";

                case 'MT - Remote + Single'
                    app.RemoteSiteList.Enable = "on";
                    app.RemoteReferenceDropDown.Enable = "on";
                    app.RemoteReferenceDropDownLabel.Enable = "on";
                    app.Remote_ListBox = 1;

                    app.MagChannelReplaceList.Enable = "off";
                    app.MagChannelReplaceDropDown.Enable = "off";
                    app.MagChannelReplaceDropDownLabel.Enable = "off";
                    app.MagReplace_ListBox = 0;

                    app.ReplaceMagChannelCheckBox.Enable = "on";
                    app.ReplaceMagChannelCheckBox.Value = 0;

                    app.StationtoProcessDropDownLabel.Text = 'Station to Process';
                    app.MagChannelReplaceDropDownLabel.Text = 'Mag Channel Replace';

                    app.RemoteReferenceDropDown.Items = list;

                case 'IP - Single Site'
                    app.RemoteSiteList.Enable = "off";
                    app.RemoteReferenceDropDown.Enable = "off";
                    app.RemoteReferenceDropDownLabel.Enable = "off";
                    app.Remote_ListBox = 0;

                    app.MagChannelReplaceList.Enable = "on";
                    app.MagChannelReplaceDropDown.Enable = "on";
                    app.MagChannelReplaceDropDownLabel.Enable = "on";
                    app.MagReplace_ListBox = 1;

                    app.ReplaceMagChannelCheckBox.Enable = "off";
                    app.ReplaceMagChannelCheckBox.Value = 1;

                    app.StationtoProcessDropDownLabel.Text = '1st E-Field Site     ';
                    app.MagChannelReplaceDropDownLabel.Text = '2nd E-Field Site     ';

                    app.RemoteReferenceDropDown.Items = " ";
                    app.MagChannelReplaceDropDown.Items = list;

                case 'IP - with Remote'
                    app.RemoteSiteList.Enable = "on";
                    app.RemoteReferenceDropDown.Enable = "on";
                    app.RemoteReferenceDropDownLabel.Enable = "on";
                    app.Remote_ListBox = 1;

                    app.MagChannelReplaceList.Enable = "on";
                    app.MagChannelReplaceDropDown.Enable = "on";
                    app.MagChannelReplaceDropDownLabel.Enable = "on";
                    app.MagReplace_ListBox = 1;

                    app.ReplaceMagChannelCheckBox.Enable = "off";
                    app.ReplaceMagChannelCheckBox.Value = 1;

                    app.StationtoProcessDropDownLabel.Text = '1st E-Field Site     ';
                    app.MagChannelReplaceDropDownLabel.Text = '2nd E-Field Site     ';

                    app.RemoteReferenceDropDown.Items = list;

                    toDeleteRR = app.RemoteReferenceDropDown.Value;
                    list(strcmp(list, toDeleteRR)) = '';
                    app.MagChannelReplaceDropDown.Items = list;
            end

            ProcessSiteList(app)
        end

        % Value changed function: HarmonicsCheckBox_2
        function HarmonicsCheckBox_2ValueChanged(app, event)
            PopulateNotch(app)
        end

        % Button pushed function: addtoQueueButton
        function addtoQueueButtonPushed(app, event)
            Batch(app)
        end

        % Button pushed function: ProcessQueueButton
        function ProcessQueueButtonPushed(app, event)
            ProcessBatch(app)
        end

        % Value changed function: ReplaceMagChannelCheckBox
        function ReplaceMagChannelCheckBoxValueChanged(app, event)
            value = app.ReplaceMagChannelCheckBox.Value;
            if value
                app.MagChannelReplaceDropDown.Visible = 'on';
                app.MagChannelReplaceList.Visible = 'on';
                app.MagChannelReplaceDropDownLabel.Visible = 'on';
                app.MagChannelReplaceDropDown.Enable = 'on';
                app.MagChannelReplaceDropDownLabel.Enable = 'on';
                app.MagChannelReplaceList.Enable = 'on';
                app.ReplaceBxCheckBox.Enable = 'on';
                app.ReplaceByCheckBox.Enable = 'on';
                list = app.StationtoProcessDropDown.Items;
                toDeleteSS = app.StationtoProcessDropDown.Value;
                toDeleteRR = app.RemoteReferenceDropDown.Value;
                list(strcmp(list, toDeleteSS)) = [];
                list(strcmp(list, toDeleteRR)) = [];
                app.MagChannelReplaceDropDown.Items = list;
            else
                app.MagChannelReplaceDropDown.Visible = 'off';
                app.MagChannelReplaceList.Visible = 'off';
                app.MagChannelReplaceDropDownLabel.Visible = 'off';
                app.MagChannelReplaceDropDown.Enable = 'off';
                app.MagChannelReplaceDropDownLabel.Enable = 'off';
                app.MagChannelReplaceList.Enable = 'off';
                app.ReplaceBxCheckBox.Enable = 'off';
                app.ReplaceByCheckBox.Enable = 'off';
            end
            UpdateMap(app)
            selectedButton = app.ProcessingOptionButtonGroup.SelectedObject;
            ProcessSiteList(app,selectedButton)
        end

        % Value changed function: MagChannelReplaceDropDown
        function MagChannelReplaceDropDownValueChanged(app, event)
            toDeleteSS = app.StationtoProcessDropDown.Value;
            toDeleteRR = app.RemoteReferenceDropDown.Value;
            list = app.StationtoProcessDropDown.Items;
            list(strcmp(list, toDeleteSS)) = [];
            list(strcmp(list, toDeleteRR)) = [];
            app.MagChannelReplaceDropDown.Items = list;
            UpdateMap(app)
            ProcessSiteList(app)
        end

        % Selection changed function: TimeSeriesTree
        function TimeSeriesTreeSelectionChanged(app, event)
            selectedNodes = app.TimeSeriesTree.SelectedNodes;
%             disp('Selected file')
%             disp(selectedNodes.Text)

%             disp('Node Children')
%             test = app.TimeSeriesTree.Children;
%             disp(test)

%             disp('Node Data')
%             disp(selectedNodes.NodeData)

            d = uiprogressdlg(app.MT_Field_App_2022b,'Title',sprintf('Loading File: %s',selectedNodes.Text),"Indeterminate",'on');

            %app.FileLoadedEditField.Value = app.SelectedFilesListBox.Value;
            File2Load = strcat(app.P_WorkingDirEditField.Value,filesep,selectedNodes.NodeData,filesep,selectedNodes.Text);
            disp('File2Load')
            disp(File2Load)
            ImportData(app,File2Load,0,d,1,0)

            d.Message = 'Detrending Data';
            Detrend_Data(app)
            d.Message = 'Plotting Timeseries Data';
            PlotTimeSeries(app)
            d.Message = 'Plotting Power Spectra';
            PlotPowerSeries(app)
            PlotFilter(app)
            close(d)
        end

        % Button pushed function: AddSite_TableButton
        function AddSite_TableButtonPushed(app, event)
            updateMetaData(app)
        end

        % Selection changed function: EDI_Tree
        function EDI_TreeCheckedNodesChanged(app, event)
            SelectedFile = app.EDI_Tree.SelectedNodes;
            fileName = strcat(app.P_WorkingDirEditField.Value,filesep,SelectedFile.NodeData,filesep,SelectedFile.Text);

            LoadEDIfile(app,fileName)
            EDI_Viewer(app)         
        end

        % Callback function: EDI_XXCheckBox, EDI_XYCheckBox,
        % EDI_YXCheckBox, 
        % ...and 6 other components
        function EDI_Viewer_Changed(app, event)
            EDI_Viewer(app)
        end

        % Value changed function: HzFilterCheckBox, NotchFilterUserCheckBox
        function ProcessFilterCheckBoxValueChanged(app, event)
            
            
            value = app.NotchFilterUserCheckBox.Value;
            if value == 1
                app.HarmonicsCheckBox_2.Enable = 'off';
                app.HzFilterCheckBox.Enable = 'off';
            else
                app.HarmonicsCheckBox_2.Enable = 'on';
                app.HzFilterCheckBox.Enable = 'on';
            end

            
            value = app.HzFilterCheckBox.Value;
            if value == 1
                app.HarmonicsCheckBox_2.Enable = 'on';
                app.NotchFilterUserCheckBox.Enable = 'off';
            else
                app.HarmonicsCheckBox_2.Enable = 'off';
                app.NotchFilterUserCheckBox.Enable = 'on';
            end
        end

        % Value changed function: SmoothHighCheckBox, SmoothLowCheckBox
        function SmoothHighCheckBoxValueChanged(app, event)
            value = app.SmoothHighCheckBox.Value;
            if value == 1
                app.SmoothLowCheckBox.Enable = 'off';
            else
                app.SmoothLowCheckBox.Enable = 'on';
            end

            value = app.SmoothLowCheckBox.Value;
            if value == 1
                app.SmoothHighCheckBox.Enable = 'off';
            else
                app.SmoothHighCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: ResetQueueButton
        function ResetQueueButtonPushed(app, event)
            app.BatchNumbers = 1;
            app.BatchList = [];
            disp('Batch Process Queue RESET')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MT_Field_App_2022b = uifigure('Visible', 'off');
            colormap(app.MT_Field_App_2022b, 'parula');
            app.MT_Field_App_2022b.Name = 'MT Field App 2020';
            app.PROCESSING = uitabgroup(app.MT_Field_App_2022b);
            app.WorkingDir = uitab(app.PROCESSING);
            app.WorkingDir.Title = 'Metadata';
            app.P_ChangeWorkingDirButton = uibutton(app.WorkingDir, 'push');
            app.P_ChangeWorkingDirButton.ButtonPushedFcn = createCallbackFcn(app, @P_ChangeWorkingDirButtonPushed, true);
            app.P_ChangeWorkingDirButton.Text = 'Change Working Dir';
            app.WorkingDirEditField_2Label = uilabel(app.WorkingDir);
            app.WorkingDirEditField_2Label.Text = 'Working Dir';
            app.P_WorkingDirEditField = uieditfield(app.WorkingDir, 'text');
            app.SelectSurveyDataButton = uibutton(app.WorkingDir, 'push');
            app.SelectSurveyDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSurveyDataButtonPushed, true);
            app.SelectSurveyDataButton.Text = 'Select Survey Data';
            app.SurveyDataEditFieldLabel = uilabel(app.WorkingDir);
            app.SurveyDataEditFieldLabel.Text = 'Survey Data';
            app.SurveyDataEditField = uieditfield(app.WorkingDir, 'text');
            app.SurveyDataUITable = uitable(app.WorkingDir);
            app.SurveyDataUITable.ColumnName = {'SiteName'; 'Project_Name'; 'Location'; 'Acquired_By'; 'Acquired_By_Org'; 'Datum'; 'Reference_Frame'; 'Latitude'; 'Longitude'; 'Elevation'; 'TimeZone'; 'StartTime'; 'FinishTime'; 'Deployment'; 'SampleRate'; 'Rx_Number'; 'Electrode_Type'; 'ExDipole'; 'ExAzimuth'; 'EyDipole'; 'EyAzimuth'; 'MagCoil_Type'; 'BxAzimuth'; 'ByAzimuth'; 'BxCoil'; 'ByCoil'; 'BzCoil'; 'BatteryNumber'; 'BatteryVoltage'; 'Resistance_NG'; 'Resistance_NS'; 'Resistance_EW'; 'Voltage_NG'; 'Voltage_NS'; 'Voltage_EW'; 'Deployment_Notes'; 'Pickup_Notes'; 'Data_Type'; 'RxType'};
            app.SurveyDataUITable.RowName = {};
            app.SurveyDataUITable.Multiselect = 'off';
            app.LemiMTDirLabel = uilabel(app.WorkingDir);
            app.LemiMTDirLabel.Text = 'LemiMT Dir';
            app.P_LEMIDirEditField_2 = uieditfield(app.WorkingDir, 'text');
            app.P_LEMIDirEditField_2.Value = 'E:\LinkLine\';
            app.AddSite_TableButton = uibutton(app.WorkingDir, 'push');
            app.AddSite_TableButton.ButtonPushedFcn = createCallbackFcn(app, @AddSite_TableButtonPushed, true);
            app.AddSite_TableButton.Text = 'Add Site';
            app.TimeSeriesTab = uitab(app.PROCESSING);
            app.TimeSeriesTab.Title = 'Time Series';
            app.Panel_TimeSeries = uipanel(app.TimeSeriesTab);
            app.TimeSeriesTree = uitree(app.TimeSeriesTab);
            app.TimeSeriesTree.SelectionChangedFcn = createCallbackFcn(app, @TimeSeriesTreeSelectionChanged, true);
            app.SelectTimeSeriesLabel = uilabel(app.TimeSeriesTab);
            app.SelectTimeSeriesLabel.Text = 'Select Time Series';
            app.PowerSpectraTab = uitab(app.PROCESSING);
            app.PowerSpectraTab.Title = 'Power Spectra';
            app.Panel_PowerSpectra = uipanel(app.PowerSpectraTab);
            app.FilterDataTab = uitab(app.PROCESSING);
            app.FilterDataTab.Title = 'Filter Data';
            app.Panel_5 = uipanel(app.FilterDataTab);
            app.RunFiltersButton = uibutton(app.Panel_5, 'push');
            app.RunFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @RunFiltersButtonPushed, true);
            app.RunFiltersButton.Text = 'Run Filters';
            app.OffsetEditField_2Label = uilabel(app.Panel_5);
            app.OffsetEditField_2Label.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.Panel_5, 'numeric');
            app.OffsetEditField.Limits = [0 1000];
            app.OffsetEditField.Value = 10;
            app.FilteredCheckBox = uicheckbox(app.Panel_5);
            app.FilteredCheckBox.Visible = 'off';
            app.FilteredCheckBox.Text = '';
            app.RemovePeriodocityCheckBox = uicheckbox(app.Panel_5);
            app.RemovePeriodocityCheckBox.Text = 'Remove Periodocity';
            app.RemoveOutliersCheckBox = uicheckbox(app.Panel_5);
            app.RemoveOutliersCheckBox.Enable = 'off';
            app.RemoveOutliersCheckBox.Text = 'Remove Outliers';
            app.PeriodPulsesEditField_2Label = uilabel(app.Panel_5);
            app.PeriodPulsesEditField_2Label.Text = 'Period Pulse (s)';
            app.PeriodPulsesEditField = uieditfield(app.Panel_5, 'numeric');
            app.FilterforDropDown_2Label = uilabel(app.Panel_5);
            app.FilterforDropDown_2Label.Text = 'Filter for';
            app.FilterforDropDown = uidropdown(app.Panel_5);
            app.FilterforDropDown.Items = {'All channels', 'Bx Ey', 'By Ex'};
            app.FilterforDropDown.Value = 'All channels';
            app.HertzCheckBox = uicheckbox(app.Panel_5);
            app.HertzCheckBox.ValueChangedFcn = createCallbackFcn(app, @HertzCheckBoxValueChanged, true);
            app.HertzCheckBox.Text = '50 Hertz';
            app.HarmonicsCheckBox = uicheckbox(app.Panel_5);
            app.HarmonicsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HarmonicsCheckBoxValueChanged, true);
            app.HarmonicsCheckBox.Text = 'Harmonics';
            app.WidthSlider = uislider(app.Panel_5);
            app.WidthSlider.Limits = [0.1 3];
            app.WidthSlider.MajorTicks = [0.1 1 2 3];
            app.WidthSlider.MajorTickLabels = {'0.1', '1', '2', '3'};
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.WidthSlider.Value = 1;
            app.UITable = uitable(app.Panel_5);
            app.UITable.ColumnName = {'NotchMin'; 'NotchMax'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ButtonAdd = uibutton(app.Panel_5, 'push');
            app.ButtonAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddPushed, true);
            app.ButtonAdd.Text = '+';
            app.ButtonDel = uibutton(app.Panel_5, 'push');
            app.ButtonDel.ButtonPushedFcn = createCallbackFcn(app, @ButtonDelPushed, true);
            app.ButtonDel.Text = '-';
            app.Panel_TimeSeries_Filt = uipanel(app.FilterDataTab);
            app.Panel_PowerSpectra_Filt = uipanel(app.FilterDataTab);
            app.ProcessDataTab = uitab(app.PROCESSING);
            app.ProcessDataTab.Title = 'Process Data';
            app.Map = uipanel(app.ProcessDataTab);
            app.StationtoProcessDropDownLabel = uilabel(app.ProcessDataTab);
            app.StationtoProcessDropDownLabel.Text = 'Station to Process';
            app.StationtoProcessDropDown = uidropdown(app.ProcessDataTab);
            app.StationtoProcessDropDown.Items = {'Load Survey Metadata'};
            app.StationtoProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @StationtoProcessDropDownValueChanged, true);
            app.StationtoProcessDropDown.Value = 'Load Survey Metadata';
            app.RemoteReferenceDropDownLabel = uilabel(app.ProcessDataTab);
            app.RemoteReferenceDropDownLabel.Text = 'Remote Reference';
            app.RemoteReferenceDropDown = uidropdown(app.ProcessDataTab);
            app.RemoteReferenceDropDown.Items = {'Load Survey Metadata', 'Test'};
            app.RemoteReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @RemoteReferenceDropDownValueChanged, true);
            app.RemoteReferenceDropDown.Value = 'Load Survey Metadata';
            app.hrsLabel = uilabel(app.ProcessDataTab);
            app.hrsLabel.Text = 'hrs';
            app.kmsLabel = uilabel(app.ProcessDataTab);
            app.kmsLabel.Text = 'kms';
            app.DistancetoRemoteEditFieldLabel = uilabel(app.ProcessDataTab);
            app.DistancetoRemoteEditFieldLabel.Text = 'Distance to Remote';
            app.DistancetoRemoteEditField = uieditfield(app.ProcessDataTab, 'numeric');
            app.TimesyncEditFieldLabel = uilabel(app.ProcessDataTab);
            app.TimesyncEditFieldLabel.Text = 'Time sync';
            app.TimesyncEditField = uieditfield(app.ProcessDataTab, 'numeric');
            app.TimesyncEditField.ValueDisplayFormat = '%5.1f';
            app.ProcessButton = uibutton(app.ProcessDataTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.OptionsPanel = uipanel(app.ProcessDataTab);
            app.OptionsPanel.Title = 'Options';
            app.CoherencePresortingCheckBox = uicheckbox(app.OptionsPanel);
            app.CoherencePresortingCheckBox.Enable = 'off';
            app.CoherencePresortingCheckBox.Text = 'Coherence Pre-sorting';
            app.HarmonicsCheckBox_2 = uicheckbox(app.OptionsPanel);
            app.HarmonicsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @HarmonicsCheckBox_2ValueChanged, true);
            app.HarmonicsCheckBox_2.Enable = 'off';
            app.HarmonicsCheckBox_2.Text = 'Harmonics';
            app.HzFilterCheckBox = uicheckbox(app.OptionsPanel);
            app.HzFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessFilterCheckBoxValueChanged, true);
            app.HzFilterCheckBox.Text = '50 Hz Filter';
            app.SmoothLowCheckBox = uicheckbox(app.OptionsPanel);
            app.SmoothLowCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothHighCheckBoxValueChanged, true);
            app.SmoothLowCheckBox.Text = 'Smooth: Low';
            app.FlipBxCheckBox = uicheckbox(app.OptionsPanel);
            app.FlipBxCheckBox.Text = 'Flip Bx';
            app.SwapMagChannelsCheckBox = uicheckbox(app.OptionsPanel);
            app.SwapMagChannelsCheckBox.Text = {'Swap Mag Channels'; ''};
            app.FlipByCheckBox = uicheckbox(app.OptionsPanel);
            app.FlipByCheckBox.Text = 'Flip By';
            app.SmoothHighCheckBox = uicheckbox(app.OptionsPanel);
            app.SmoothHighCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothHighCheckBoxValueChanged, true);
            app.SmoothHighCheckBox.Text = 'Smooth: High';
            app.ReplaceMagChannelCheckBox = uicheckbox(app.OptionsPanel);
            app.ReplaceMagChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReplaceMagChannelCheckBoxValueChanged, true);
            app.ReplaceMagChannelCheckBox.Enable = 'off';
            app.ReplaceMagChannelCheckBox.Text = {'Replace Mag Channel'; ''};
            app.EqualizeEDIFreqCheckBox = uicheckbox(app.OptionsPanel);
            app.EqualizeEDIFreqCheckBox.Text = 'Equalize EDI Freq';
            app.ReplaceBxCheckBox = uicheckbox(app.OptionsPanel);
            app.ReplaceBxCheckBox.Enable = 'off';
            app.ReplaceBxCheckBox.Text = 'Replace Bx';
            app.ReplaceByCheckBox = uicheckbox(app.OptionsPanel);
            app.ReplaceByCheckBox.Enable = 'off';
            app.ReplaceByCheckBox.Text = 'Replace By';
            app.NotchFilterUserCheckBox = uicheckbox(app.OptionsPanel);
            app.NotchFilterUserCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessFilterCheckBoxValueChanged, true);
            app.NotchFilterUserCheckBox.Text = 'Filter Data USER SELECTED (Filter Data TAB)';
            app.Process_Dec_Panel = uipanel(app.ProcessDataTab);
            app.Process_Dec_Panel.Title = 'Process Freq';
            app.CheckBox500Hz = uicheckbox(app.Process_Dec_Panel);
            app.CheckBox500Hz.Text = '500 Hz';
            app.CheckBox250Hz = uicheckbox(app.Process_Dec_Panel);
            app.CheckBox250Hz.Text = '250 Hz';
            app.CheckBox31_25Hz = uicheckbox(app.Process_Dec_Panel);
            app.CheckBox31_25Hz.Text = '31.25 Hz';
            app.CheckBox62_5Hz = uicheckbox(app.Process_Dec_Panel);
            app.CheckBox62_5Hz.Text = '62.5 Hz';
            app.CheckBox1000Hz = uicheckbox(app.Process_Dec_Panel);
            app.CheckBox1000Hz.Text = '1000 Hz';
            app.CheckBox15_625Hz = uicheckbox(app.Process_Dec_Panel);
            app.CheckBox15_625Hz.Text = '15.625 Hz';
            app.CheckBox7_8125Hz = uicheckbox(app.Process_Dec_Panel);
            app.CheckBox7_8125Hz.Text = '7.8125 Hz';
            app.CheckBox125Hz = uicheckbox(app.Process_Dec_Panel);
            app.CheckBox125Hz.Text = '125 Hz';
            app.SingleSiteList = uilistbox(app.ProcessDataTab);
            app.SingleSiteList.Items = {};
            app.SingleSiteList.Multiselect = 'on';
            app.SingleSiteList.Value = {};
            app.RemoteSiteList = uilistbox(app.ProcessDataTab);
            app.RemoteSiteList.Items = {};
            app.RemoteSiteList.Multiselect = 'on';
            app.RemoteSiteList.Value = {};
            app.ProcessingOptionButtonGroup = uibuttongroup(app.ProcessDataTab);
            app.ProcessingOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessingOptionButtonGroupSelectionChanged, true);
            app.ProcessingOptionButtonGroup.Title = 'Processing Option';
            app.MT_withRR_ProcessButton = uitogglebutton(app.ProcessingOptionButtonGroup);
            app.MT_withRR_ProcessButton.Text = 'MT - with Remote';
            app.MT_withRR_ProcessButton.Value = true;
            app.MT_withSS_ProcessButton = uitogglebutton(app.ProcessingOptionButtonGroup);
            app.MT_withSS_ProcessButton.Text = 'MT - Single Site';
            app.MT_withRRSS_ProcessButton = uitogglebutton(app.ProcessingOptionButtonGroup);
            app.MT_withRRSS_ProcessButton.Enable = 'off';
            app.MT_withRRSS_ProcessButton.Text = 'MT - Remote + Single';
            app.IP_withSS_ProcessButton = uitogglebutton(app.ProcessingOptionButtonGroup);
            app.IP_withSS_ProcessButton.Enable = 'off';
            app.IP_withSS_ProcessButton.Text = 'IP - Single Site';
            app.IP_withRR_ProcessButton = uitogglebutton(app.ProcessingOptionButtonGroup);
            app.IP_withRR_ProcessButton.Enable = 'off';
            app.IP_withRR_ProcessButton.Text = 'IP - with Remote';
            app.addtoQueueButton = uibutton(app.ProcessDataTab, 'push');
            app.addtoQueueButton.ButtonPushedFcn = createCallbackFcn(app, @addtoQueueButtonPushed, true);
            app.addtoQueueButton.Text = 'add to Queue';
            app.ProcessQueueButton = uibutton(app.ProcessDataTab, 'push');
            app.ProcessQueueButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessQueueButtonPushed, true);
            app.ProcessQueueButton.Text = 'Process Queue';
            app.MagChannelReplaceDropDownLabel = uilabel(app.ProcessDataTab);
            app.MagChannelReplaceDropDownLabel.Enable = 'off';
            app.MagChannelReplaceDropDownLabel.Text = 'Mag Channel Replace';
            app.MagChannelReplaceDropDown = uidropdown(app.ProcessDataTab);
            app.MagChannelReplaceDropDown.Items = {'Load Survey Metadata', 'Test'};
            app.MagChannelReplaceDropDown.ValueChangedFcn = createCallbackFcn(app, @MagChannelReplaceDropDownValueChanged, true);
            app.MagChannelReplaceDropDown.Enable = 'off';
            app.MagChannelReplaceDropDown.Value = 'Load Survey Metadata';
            app.MagChannelReplaceList = uilistbox(app.ProcessDataTab);
            app.MagChannelReplaceList.Items = {};
            app.MagChannelReplaceList.Multiselect = 'on';
            app.MagChannelReplaceList.Enable = 'off';
            app.MagChannelReplaceList.Value = {};
            app.Panel_Slider = uipanel(app.ProcessDataTab);
            app.StartSpinnerLabel = uilabel(app.Panel_Slider);
            app.StartSpinnerLabel.Visible = 'off';
            app.StartSpinnerLabel.Text = 'Start';
            app.StartSpinner = uispinner(app.Panel_Slider);
            app.StartSpinner.Step = 1800;
            app.StartSpinner.ValueDisplayFormat = '%.0f';
            app.StartSpinner.Editable = 'off';
            app.StartSpinner.Enable = 'off';
            app.StartSpinner.Visible = 'off';
            app.FinishSpinnerLabel = uilabel(app.Panel_Slider);
            app.FinishSpinnerLabel.Visible = 'off';
            app.FinishSpinnerLabel.Text = 'Finish';
            app.FinishSpinner = uispinner(app.Panel_Slider);
            app.FinishSpinner.Step = 1800;
            app.FinishSpinner.ValueDisplayFormat = '%.0f';
            app.FinishSpinner.Editable = 'off';
            app.FinishSpinner.Enable = 'off';
            app.FinishSpinner.Visible = 'off';
            app.StartEditFieldLabel = uilabel(app.Panel_Slider);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Panel_Slider, 'text');
            app.StartEditField.Editable = 'off';
            app.FinishEditFieldLabel = uilabel(app.Panel_Slider);
            app.FinishEditFieldLabel.Text = 'Finish';
            app.FinishEditField = uieditfield(app.Panel_Slider, 'text');
            app.FinishEditField.Editable = 'off';
            app.ResetQueueButton = uibutton(app.ProcessDataTab, 'push');
            app.ResetQueueButton.ButtonPushedFcn = createCallbackFcn(app, @ResetQueueButtonPushed, true);
            app.ResetQueueButton.Text = 'Reset Queue';
            app.ViewEDIsTab = uitab(app.PROCESSING);
            app.ViewEDIsTab.Title = 'View EDIs';
            app.SelectEDIfilesLabel = uilabel(app.ViewEDIsTab);
            app.SelectEDIfilesLabel.Text = 'Select EDI files';
            app.EDI_display = uipanel(app.ViewEDIsTab);
            app.Panel_7 = uipanel(app.ViewEDIsTab);
            app.EDI_XYCheckBox = uicheckbox(app.Panel_7);
            app.EDI_XYCheckBox.ValueChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.EDI_XYCheckBox.Text = 'XY';
            app.EDI_XYCheckBox.Value = true;
            app.EDI_YXCheckBox = uicheckbox(app.Panel_7);
            app.EDI_YXCheckBox.ValueChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.EDI_YXCheckBox.Text = 'YX';
            app.EDI_YXCheckBox.Value = true;
            app.EDI_XXCheckBox = uicheckbox(app.Panel_7);
            app.EDI_XXCheckBox.ValueChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.EDI_XXCheckBox.Text = 'XX';
            app.EDI_YYCheckBox = uicheckbox(app.Panel_7);
            app.EDI_YYCheckBox.ValueChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.EDI_YYCheckBox.Text = 'YY';
            app.PeriodsLabel = uilabel(app.Panel_7);
            app.PeriodsLabel.Text = 'Period (s)';
            app.MinEditFieldLabel = uilabel(app.Panel_7);
            app.MinEditFieldLabel.Text = 'Min';
            app.XAxis_MinEditField = uieditfield(app.Panel_7, 'numeric');
            app.XAxis_MinEditField.ValueChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.XAxis_MinEditField.Value = 0.001;
            app.MaxLabel = uilabel(app.Panel_7);
            app.MaxLabel.Text = 'Max';
            app.XAxis_MaxEditField = uieditfield(app.Panel_7, 'numeric');
            app.XAxis_MaxEditField.ValueDisplayFormat = '%.0f';
            app.XAxis_MaxEditField.ValueChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.XAxis_MaxEditField.Value = 10000;
            app.PhaseLabel = uilabel(app.Panel_7);
            app.PhaseLabel.Text = 'Phase';
            app.Phase_ButtonGroup = uibuttongroup(app.Panel_7);
            app.Phase_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.Phase_360_Button = uiradiobutton(app.Phase_ButtonGroup);
            app.Phase_360_Button.Text = '(-360,360)';
            app.Phase_180_Button = uiradiobutton(app.Phase_ButtonGroup);
            app.Phase_180_Button.Text = '(-180,180)';
            app.Phase_180_Button.Value = true;
            app.Phase_90_Button = uiradiobutton(app.Phase_ButtonGroup);
            app.Phase_90_Button.Text = '(0,90)';
            app.AppResistivityLabel = uilabel(app.Panel_7);
            app.AppResistivityLabel.Text = 'App Resistivity';
            app.MinEditFieldLabel_2 = uilabel(app.Panel_7);
            app.MinEditFieldLabel_2.Text = 'Min';
            app.YAxis_MinEditField = uieditfield(app.Panel_7, 'numeric');
            app.YAxis_MinEditField.ValueChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.YAxis_MinEditField.Value = 0.1;
            app.MaxLabel_2 = uilabel(app.Panel_7);
            app.MaxLabel_2.Text = 'Max';
            app.YAxis_MaxEditField = uieditfield(app.Panel_7, 'numeric');
            app.YAxis_MaxEditField.ValueDisplayFormat = '%.0f';
            app.YAxis_MaxEditField.ValueChangedFcn = createCallbackFcn(app, @EDI_Viewer_Changed, true);
            app.YAxis_MaxEditField.Value = 1000;
            app.ViewingOptionsLabel = uilabel(app.ViewEDIsTab);
            app.ViewingOptionsLabel.Text = 'Viewing Options';
            app.EDI_Tree = uitree(app.ViewEDIsTab);
            app.EDI_Tree.SelectionChangedFcn = createCallbackFcn(app, @EDI_TreeCheckedNodesChanged, true);
            app.ProcessFreqList = uitable(app.MT_Field_App_2022b);
            app.ProcessFreqList.ColumnName = {'Column 1'};
            app.ProcessFreqList.RowName = {};
            app.ProcessFreqList.Visible = 'off';
            % Show the figure after all components are created
            app.MT_Field_App_2022b.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MT_Field_App_2022b_mk72

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MT_Field_App_2022b)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MT_Field_App_2022b)
        end
    end
end